classdef Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InterfaceSimulateurUIFigure     matlab.ui.Figure
        ResultGroup                     matlab.ui.container.TabGroup
        RsultatsTab                     matlab.ui.container.Tab
        ResultUITable                   matlab.ui.control.Table
        OscilloscopeTab                 matlab.ui.container.Tab
        TensionButton                   matlab.ui.control.Button
        PositioncordeButton             matlab.ui.control.Button
        PositioncapteButton             matlab.ui.control.Button
        Sortie05VButton                 matlab.ui.control.Button
        FrquenceButton                  matlab.ui.control.Button
        ActuateurButton                 matlab.ui.control.Button
        RgulateurButton                 matlab.ui.control.Button
        AnimationdelacordeTab           matlab.ui.container.Tab
        UIAxesAnimation                 matlab.ui.control.UIAxes
        JouerButton                     matlab.ui.control.Button
        InterrompreButton               matlab.ui.control.Button
        RalentissementEditFieldLabel    matlab.ui.control.Label
        RalentissementEditField         matlab.ui.control.EditField
        SimulationDropDown_2Label       matlab.ui.control.Label
        SimulationDropDown_Animation    matlab.ui.control.DropDown
        PositiondunsegmentTab           matlab.ui.container.Tab
        SegmentDropDownLabel            matlab.ui.control.Label
        SegmentDropDown                 matlab.ui.control.DropDown
        SimulationDropDownLabel         matlab.ui.control.Label
        SimulationDropDown              matlab.ui.control.DropDown
        UIAxesPositionSegment           matlab.ui.control.UIAxes
        PerformanceTab                  matlab.ui.container.Tab
        UIAxesPerformance               matlab.ui.control.UIAxes
        ParamtreDropDownLabel           matlab.ui.control.Label
        PerformanceParamtreDropDown     matlab.ui.control.DropDown
        CritreDropDownLabel             matlab.ui.control.Label
        PerformanceCritreDropDown       matlab.ui.control.DropDown
        PerformanceParameterGroupTable  matlab.ui.control.Table
        LigneDropDownLabel              matlab.ui.control.Label
        LigneDropDown                   matlab.ui.control.DropDown
        LesAccords2Label                matlab.ui.control.Label
        SimulerButton                   matlab.ui.control.Button
        EnregistrerButton               matlab.ui.control.Button
        ChargerButton                   matlab.ui.control.Button
        RinitialiserButton              matlab.ui.control.Button
        StatutLabel                     matlab.ui.control.Label
        ParameterGroup                  matlab.ui.container.TabGroup
        ActionTab                       matlab.ui.container.Tab
        AutoentretienSwitchLabel        matlab.ui.control.Label
        AutoentretienSwitch             matlab.ui.control.Switch
        AutoaccordSwitchLabel           matlab.ui.control.Label
        AutoaccordSwitch                matlab.ui.control.Switch
        PerturbationOffsetPanel         matlab.ui.container.Panel
        NoiseOffSetSwitch               matlab.ui.control.Switch
        NoiseOffsetX                    matlab.ui.control.NumericEditField
        PerturbationSinePanel           matlab.ui.container.Panel
        NoiseSineSwitch                 matlab.ui.control.Switch
        AEditFieldLabel                 matlab.ui.control.Label
        NoiseSineA                      matlab.ui.control.NumericEditField
        wEditFieldLabel                 matlab.ui.control.Label
        NoiseSineW                      matlab.ui.control.NumericEditField
        PerturbationBruitblancPanel     matlab.ui.container.Panel
        NoiseWhiteSwitch                matlab.ui.control.Switch
        varLabel                        matlab.ui.control.Label
        NoiseWhiteVar                   matlab.ui.control.NumericEditField
        MeanLabel                       matlab.ui.control.Label
        NoiseWhiteMean                  matlab.ui.control.NumericEditField
        ManualFrequenceSwitch           matlab.ui.control.ToggleSwitch
        FrquenceEditFieldLabel          matlab.ui.control.Label
        ManualFrequence                 matlab.ui.control.NumericEditField
        CordeTab                        matlab.ui.container.Tab
        MasseliniqueEditFieldLabel      matlab.ui.control.Label
        MasseliniqueEditField           matlab.ui.control.EditField
        LongueurEditFieldLabel          matlab.ui.control.Label
        LongueurEditField               matlab.ui.control.EditField
        FrottementEditFieldLabel        matlab.ui.control.Label
        FrottementEditField             matlab.ui.control.EditField
        TensionmcaniqueEditFieldLabel   matlab.ui.control.Label
        TensionmcaniqueEditField        matlab.ui.control.EditField
        Segmentsx0EditFieldLabel        matlab.ui.control.Label
        Segmentsx0EditField             matlab.ui.control.EditField
        Segmentsv0EditFieldLabel        matlab.ui.control.Label
        Segmentsv0EditField             matlab.ui.control.EditField
        ValeursphysiquesLabel           matlab.ui.control.Label
        ValeursinitialesLabel           matlab.ui.control.Label
        ConfigurationTab                matlab.ui.container.Tab
        PositionrelativeducapteurLabel  matlab.ui.control.Label
        PositionrelativeducapteurEditField  matlab.ui.control.EditField
        PositionrelativedelactuateurEditFieldLabel  matlab.ui.control.Label
        PositionrelativedelactuateurEditField  matlab.ui.control.EditField
        DistancecapteurcordeEditFieldLabel  matlab.ui.control.Label
        DistancecapteurcordeEditField   matlab.ui.control.EditField
        AccordPEditFieldLabel           matlab.ui.control.Label
        AccordPEditField                matlab.ui.control.EditField
        AccordIEditFieldLabel           matlab.ui.control.Label
        AccordIEditField                matlab.ui.control.EditField
        PositionnementLabel             matlab.ui.control.Label
        AccordeurLabel                  matlab.ui.control.Label
        HarmoniqueSwitchLabel           matlab.ui.control.Label
        HarmoniqueSwitch                matlab.ui.control.Switch
        AvancTab                        matlab.ui.container.Tab
        PasdesimulationEditFieldLabel   matlab.ui.control.Label
        PasdesimulationEditField        matlab.ui.control.EditField
        NombredesegmentsLabel           matlab.ui.control.Label
        NombredesegmentsEditField       matlab.ui.control.EditField
        GainTensionAngleEditFieldLabel  matlab.ui.control.Label
        GainTensionAngleEditField       matlab.ui.control.EditField
        TempsdesaisieEditFieldLabel     matlab.ui.control.Label
        TempsdesaisieEditField          matlab.ui.control.EditField
        RfrencefrquenceEditFieldLabel   matlab.ui.control.Label
        RfrencefrquenceEditField        matlab.ui.control.EditField
        VitessemaximaleEditFieldLabel   matlab.ui.control.Label
        VitessemaximaleEditField        matlab.ui.control.EditField
        TempsderponseEditFieldLabel     matlab.ui.control.Label
        TempsderponseEditField          matlab.ui.control.EditField
        GainAngleTempsdimpulsionEditFieldLabel  matlab.ui.control.Label
        GainAngleTempsdimpulsionEditField  matlab.ui.control.EditField
        CordeLabel                      matlab.ui.control.Label
        MesuredefrquenceLabel           matlab.ui.control.Label
        ServomoteurLabel                matlab.ui.control.Label
        ClefLabel                       matlab.ui.control.Label
        CircuitTab                      matlab.ui.container.Tab
        HystrsisVaEditFieldLabel        matlab.ui.control.Label
        HystrsisVaEditField             matlab.ui.control.EditField
        HystrsisVbEditFieldLabel        matlab.ui.control.Label
        HystrsisVbEditField             matlab.ui.control.EditField
        LimitedetensionEditFieldLabel   matlab.ui.control.Label
        LimitedetensionEditField        matlab.ui.control.EditField
        GainLumireCourantEditFieldLabel  matlab.ui.control.Label
        GainLumireCourantEditField      matlab.ui.control.EditField
        CircuitPassebasEditFieldLabel   matlab.ui.control.Label
        CircuitPassebasEditField        matlab.ui.control.EditField
        CircuitPassehautEditFieldLabel  matlab.ui.control.Label
        CircuitPassehautEditField       matlab.ui.control.EditField
        GainVoltageForceEditFieldLabel  matlab.ui.control.Label
        GainVoltageForceEditField       matlab.ui.control.EditField
        InductanceEditFieldLabel        matlab.ui.control.Label
        InductanceEditField             matlab.ui.control.EditField
        GainCourantVoltageEditFieldLabel  matlab.ui.control.Label
        GainCourantVoltageEditField     matlab.ui.control.EditField
        ActuateurLabel                  matlab.ui.control.Label
        CapteurLabel                    matlab.ui.control.Label
        CircuitLabel                    matlab.ui.control.Label
        NotesTab                        matlab.ui.container.Tab
        CommentaireTextAreaLabel        matlab.ui.control.Label
        CommentaireTextArea             matlab.ui.control.TextArea
        HiddenGroup                     matlab.ui.container.TabGroup
        hiddenTab                       matlab.ui.container.Tab
        FrquencedemandeHzEditFieldLabel  matlab.ui.control.Label
        FrquencedemandeHzEditField      matlab.ui.control.EditField
        DuredesimulationEditFieldLabel  matlab.ui.control.Label
        DuredesimulationEditField       matlab.ui.control.NumericEditField
        NoteOptionSwitch                matlab.ui.control.ToggleSwitch
        NoteDropDownLabel               matlab.ui.control.Label
        NoteDropDown                    matlab.ui.control.DropDown
        FrquenceinitialeLabel           matlab.ui.control.Label
        FrquenceinitialeEditField       matlab.ui.control.NumericEditField
        TempsconsigneEditFieldLabel     matlab.ui.control.Label
        TempsconsigneEditField          matlab.ui.control.NumericEditField
    end

    properties (Access = private)
        SimulationParams SimulationParameterManager;% Description
        Logger ErrorLogger;
        simulatorManager SimulatorManager;
        CordeAnimator AnimationCordeCreator;
        simulationDataFormator SimulationDataFormator;
        performanceGraphManager PerformanceGraphManager;
        
        flagStopAnimation = false;
        flagSimulationEnCours = false;
    end
    
    methods (Access = private)
        
        function results = ParseDoublesStrOrExpr(app, value)
            try
                splittedValue =  strsplit(value,';');
                valuesAsDouble = cellfun( @(x) str2double(x),splittedValue);
                if(size(valuesAsDouble,2)==1)
                    if(isnan(valuesAsDouble))
                        results = eval(value);
                    else
                        results(1) = valuesAsDouble;
                    end
                elseif(any(arrayfun(@(x) isnan(x), valuesAsDouble)))
                    results = eval(value);
                else
                    results = valuesAsDouble;
                end
            catch ME
                app.Logger.Warn(ME.message);
                results = double.empty(0);
            end
        end
        
        function SetStatut(app, statut)
            app.StatutLabel.Text = sprintf('Statut : %s', mlreportgen.utils.toString(statut));
        end
        
        function results = SetParsedValue(app,field)
            results = ParseDoublesStrOrExpr(app, field.Value);
            field.Value = app.FormatDoubleArrayToString(results);
        end
        
        function UpdateFieldsToSimulationParams(app)
            app.DuredesimulationEditField.Value = app.SimulationParams.Duration;
            app.FrquencedemandeHzEditField.Value = app.FormatDoubleArrayToString(app.SimulationParams.Frequence_final);
            app.MasseliniqueEditField.Value =  app.FormatDoubleArrayToString(app.SimulationParams.LinearDensity);
            app.LongueurEditField.Value =  app.FormatDoubleArrayToString(app.SimulationParams.Length);
            app.PositionrelativeducapteurEditField.Value =  app.FormatDoubleArrayToString(app.SimulationParams.pos_capteur_relative);
            app.PositionrelativedelactuateurEditField.Value =  app.FormatDoubleArrayToString(app.SimulationParams.pos_actuateur_relative);
            
            app.FrottementEditField.Value =app.FormatDoubleArrayToString(app.SimulationParams.frottement);
            app.TensionmcaniqueEditField.Value = app.FormatDoubleArrayToString(app.SimulationParams.T_0);
            app.Segmentsx0EditField.Value = app.FormatDoubleArrayToString(app.SimulationParams.x_0);
            app.Segmentsv0EditField.Value = app.FormatDoubleArrayToString(app.SimulationParams.v_0);
            app.DistancecapteurcordeEditField.Value = app.FormatDoubleArrayToString(app.SimulationParams.position_centre);
            app.PasdesimulationEditField.Value = app.FormatDoubleArrayToString(app.SimulationParams.dt);
            app.NombredesegmentsEditField.Value = app.FormatDoubleArrayToString(app.SimulationParams.N);
            
            app.GainVoltageForceEditField.Value = app.FormatDoubleArrayToString(app.SimulationParams.actuateur_force_magnetique);
            app.InductanceEditField.Value = app.FormatDoubleArrayToString(app.SimulationParams.actuateur_circuitRL_s);
            app.GainCourantVoltageEditField.Value = app.FormatDoubleArrayToString(app.SimulationParams.capteur_R3);
            app.CircuitPassehautEditField.Value = app.FormatDoubleArrayToString(app.SimulationParams.circuit_passe_haut);
            app.CircuitPassebasEditField.Value = app.FormatDoubleArrayToString(app.SimulationParams.circuit_passe_bas);
            app.AccordPEditField.Value = app.FormatDoubleArrayToString(app.SimulationParams.regulateur_gain);
            app.GainTensionAngleEditField.Value = app.FormatDoubleArrayToString(app.SimulationParams.servoclef_clef);
            app.TempsdesaisieEditField.Value = app.FormatDoubleArrayToString(app.SimulationParams.freq_mes_transfer);
            
            app.RfrencefrquenceEditField.Value = app.FormatDoubleArrayToString(app.SimulationParams.clockFreq);
            app.VitessemaximaleEditField.Value = app.FormatDoubleArrayToString(app.SimulationParams.servoClefVitesseMax);
            app.TempsderponseEditField.Value = app.FormatDoubleArrayToString(app.SimulationParams.freq_coupure);
            app.GainAngleTempsdimpulsionEditField.Value = app.FormatDoubleArrayToString(app.SimulationParams.gainVoltageAngle);
            app.HystrsisVaEditField.Value = app.FormatDoubleArrayToString(app.SimulationParams.Circuit_Va);
            app.HystrsisVbEditField.Value = app.FormatDoubleArrayToString(app.SimulationParams.Circuit_Vb);
            app.LimitedetensionEditField.Value = app.FormatDoubleArrayToString(app.SimulationParams.tensionAjustable);
            app.GainLumireCourantEditField.Value = app.FormatDoubleArrayToString(app.SimulationParams.capteur_courantMax);
%             app.InverserpolarisationCheckBox.Value = app.SimulationParams.IsPolarisationInverted;
            app.AccordIEditField.Value = app.FormatDoubleArrayToString(app.SimulationParams.regulateur_accordI);
            
            app.FrquenceinitialeEditField.Value = app.SimulationParams.Frequence_initial;
            app.TempsconsigneEditField.Value = app.SimulationParams.Frequence_time_step;
            
            %Actions
            app.AutoaccordSwitch.Value = app.GetSwitchAllumeStatus(app.SimulationParams.simulationActionParameter.IsAutoAccordOn);
            app.AutoentretienSwitch.Value = app.GetSwitchAllumeStatus(app.SimulationParams.simulationActionParameter.IsAutoEntretienOn);
            
            if(app.SimulationParams.simulationActionParameter.ManualFreqIsOn)
                app.ManualFrequenceSwitch.Value = "Entretien Manuel";
            else
                app.ManualFrequenceSwitch.Value = "Consigne";
            end
            
            app.ManualFrequence.Value =  app.SimulationParams.simulationActionParameter.ManualFreqValue;
            
            app.NoiseOffSetSwitch.Value = app.GetSwitchAllumeStatus(app.SimulationParams.simulationActionParameter.OffSetIsOn);
            app.NoiseOffsetX.Value =  app.SimulationParams.simulationActionParameter.OffSetValue;
            
            app.NoiseSineSwitch.Value = app.GetSwitchAllumeStatus(app.SimulationParams.simulationActionParameter.SineNoiseIsOn);
            app.NoiseSineA.Value = app.SimulationParams.simulationActionParameter.SineNoiseA;
            app.NoiseSineW.Value = app.SimulationParams.simulationActionParameter.SineNoiseW;
            
            app.NoiseWhiteSwitch.Value = app.GetSwitchAllumeStatus(app.SimulationParams.simulationActionParameter.WhiteNoiseIsOn);
            app.NoiseWhiteVar.Value = app.SimulationParams.simulationActionParameter.WhiteNoiseVar;
            app.NoiseWhiteMean.Value = app.SimulationParams.simulationActionParameter.WhiteNoiseMean;
            
            app.HarmoniqueSwitch.Value = num2str(app.SimulationParams.Harmonique);
        end
        
        function FinishSingleSimulationHandler(app,~, eventData)
            %if id = 1, show graphs.
            id = eventData.simulationId;
            if(id == 1)
                app.AnimationdelacordeTab.Parent = app.ResultGroup;
                app.PositiondunsegmentTab.Parent = app.ResultGroup;
                app.SimulationDropDown_Animation.Items = {'1'};
                app.SimulationDropDown.Items = {'1'};
                app.SegmentDropDown.Items = arrayfun(@(x) num2str(x), 1:(eventData.param.corde.N), 'UniformOutput',false);
                
%                 app.PerformanceParameterGroupTable.Visible = false;
                
                if(~isempty( app.simulationDataFormator.varyingParams))
                    app.PerformanceTab.Parent = app.ResultGroup;
                    app.PerformanceParamtreDropDown.Items = app.simulationDataFormator.ParameterLabelName;
                    app.PerformanceParamtreDropDown.Value = app.simulationDataFormator.ParameterLabelName{1};
                    
                    app.PerformanceParamtreDropDownValueChanged(eventData);
                end
                
            else
                app.SimulationDropDown_Animation.Items = [app.SimulationDropDown_Animation.Items num2str(id)];
                app.SimulationDropDown.Items = [app.SimulationDropDown.Items num2str(id)];
            end
            %What else? If Sim 1, activate the new tab with the graph and
            %The graph will have all params and everything and will call
            %the data from SimResultFormator
        end
    end
    
    methods
        function resultStr = FormatDoubleArrayToString(~,values)
            resultStr = strjoin(arrayfun(@(value) sprintf('%.3g',value), values, 'UniformOutput', false),';');
        end
        function value = GetSwitchAllumeStatus(~,isOn)
            if(isOn)
                value = "Allumé";
            else
                value = "";
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Logger = ErrorLogger(app.StatutLabel, app.InterfaceSimulateurUIFigure);
            app.SimulationParams = SimulationParameterManager();
            simulator = SystemSimulator();
            app.simulatorManager = SimulatorManager(simulator);
            app.CordeAnimator = AnimationCordeCreator();
            app.simulationDataFormator = SimulationDataFormator(@(rowsDatas) set(app.ResultUITable, 'data', rowsDatas));
            app.performanceGraphManager = PerformanceGraphManager(app.simulationDataFormator);
            
            app.simulatorManager.add_listener_formator(app.simulationDataFormator);
            addlistener(app.simulationDataFormator, 'NewRowAdded', @app.FinishSingleSimulationHandler);
            
            set(app.ResultUITable, 'data', app.simulationDataFormator.rowsDatas);
            
            app.Reset([]); %Set everything to default.
            
            %Stupid app designer doesn't let me change axes at creation.
            %Apparently this is a bug.
            title(app.UIAxesPositionSegment, 'Position d''un segment');
            xlabel(app.UIAxesPositionSegment, 'Temps');
            ylabel(app.UIAxesPositionSegment, 'Position');
        end

        % Button pushed function: SimulerButton
        function Simulate(app, event)
            %Set status
            if(app.flagSimulationEnCours)
                return;
            end
            app.flagSimulationEnCours = true;
            app.SetStatut("Simulation en cours.");
            app.SimulerButton.Enable = false;
            app.ChargerButton.Enable = false;
            app.RinitialiserButton.Text = 'Arrêter';
            
            app.SimulationDropDown.Items = {'1'};
            app.SimulationDropDown.Value = '1';
            app.SimulationDropDown_Animation.Items = app.SimulationDropDown.Items;
            app.SimulationDropDown_Animation.Value = '1';
            app.PerformanceTab.Parent = app.HiddenGroup;
            app.AnimationdelacordeTab.Parent = app.HiddenGroup;
            app.PositiondunsegmentTab.Parent = app.HiddenGroup;  
            
            %Start simulation
            app.simulatorManager.SetAutoEntretien(true);
            app.simulatorManager.SetAutoAccord(true);
            app.simulationDataFormator.Reset();
            app.simulationDataFormator.SetSimulation(app.SimulationParams);
            app.ResultUITable.ColumnName = app.simulationDataFormator.ColumnName;
            set(app.ResultUITable, 'data', {});
            
            app.simulatorManager.Simulate(app.SimulationParams.EnumerateSimulationParameters(), @(s) app.SetStatut(s));
            
            app.EnregistrerButton.Enable = true;
            app.ChargerButton.Enable = true;
            app.SimulerButton.Enable = true;
            
            %Show corresponding tab + set position segment
            NnSim = app.SimulationParams.GetNumberOfSimulation();
            
            %when we show   app.SegmentDropDown.Items = arrayfun(@(x) num2str(x), 1:(app.simulatorManager.resultsByParameter{1}{1}.corde.N), 'UniformOutput',false);
            
            %      app.simulationDataFormator.SetResults(app.simulatorManager.resultsByParameter);
            %             %Done at each iteration now.
            %           %  set(app.ResultUITable, 'data', app.simulationDataFormator.rowsDatas);
            
            app.SetStatut(sprintf('Simulations terminées (%i)', NnSim));
            app.flagSimulationEnCours = false;
            app.RinitialiserButton.Text = 'Réinitialiser';
        end

        % Button pushed function: RinitialiserButton
        function Reset(app, event)
            %Set component status
            if(app.RinitialiserButton.Text == "Arrêter")
                app.simulatorManager.Stop();
                app.RinitialiserButton.Text = 'Réinitialiser';
                app.SimulerButton.Enable = true;
                app.ChargerButton.Enable = true;
                app.flagSimulationEnCours = false;
            else
                app.SimulerButton.Enable = true;
                app.ChargerButton.Enable = true;
                app.EnregistrerButton.Enable = false;
                app.SimulationParams.SetToDefault();
                UpdateFieldsToSimulationParams(app);
                
%                 app.HarmoniqueSpinnerValueChanged(event);
                app.ManualFrequenceSwitchValueChanged(event);
                app.AutoaccordSwitchValueChanged(event);
                app.AutoentretienSwitchValueChanged(event);
                app.NoiseOffSetSwitchValueChanged(event);
                app.NoiseSineSwitchValueChanged(event);
                app.NoiseWhiteSwitchValueChanged(event);
                
                app.AnimationdelacordeTab.Parent = app.HiddenGroup;
                app.PositiondunsegmentTab.Parent = app.HiddenGroup;
                app.PerformanceTab.Parent = app.HiddenGroup;
                app.PerformanceParamtreDropDown.Items = {''};
                app.PerformanceParamtreDropDown.Value = '';
                app.PerformanceCritreDropDown.Items = app.simulationDataFormator.criteriaName;
                app.PerformanceCritreDropDown.Value = app.PerformanceCritreDropDown.Items{1};
                set(app.PerformanceParameterGroupTable, 'data', {})

                %Data table tab
                app.ResultUITable.ColumnName = SimulationDataFormator.GetDefaultColumnName;
                set(app.ResultUITable, 'data', {});
                app.flagSimulationEnCours = false;
                app.SetStatut("Prêt.");                
            end
            
            
        end

        % Value changed function: FrquencedemandeHzEditField
        function EditFrequence(app, event)
            app.SimulationParams.Frequence_final = SetParsedValue(app, app.FrquencedemandeHzEditField);
        end

        % Value changed function: MasseliniqueEditField
        function EditMassLineique(app, event)
            app.SimulationParams.LinearDensity = SetParsedValue(app, app.MasseliniqueEditField);
        end

        % Value changed function: LongueurEditField
        function EditLongueur(app, event)
            app.SimulationParams.Length = SetParsedValue(app, app.LongueurEditField);
        end

        % Value changed function: DuredesimulationEditField
        function EditDuree(app, event)
            app.SimulationParams.Duration = app.DuredesimulationEditField.Value;
        end

        % Value changed function: PositionrelativeducapteurEditField
        function EditPositionCapteur(app, event)
            app.SimulationParams.pos_capteur_relative = SetParsedValue(app, app.PositionrelativeducapteurEditField);
        end

        % Value changed function: 
        % PositionrelativedelactuateurEditField
        function EditPositionActuateur(app, event)
            app.SimulationParams.pos_actuateur_relative = SetParsedValue(app, app.PositionrelativedelactuateurEditField);
        end

        % Value changed function: FrottementEditField
        function FrottementEditFieldValueChanged(app, event)
            app.SimulationParams.frottement = SetParsedValue(app, app.FrottementEditField);
        end

        % Value changed function: TensionmcaniqueEditField
        function TensionmcaniqueEditFieldValueChanged(app, event)
            app.SimulationParams.T_0 = SetParsedValue(app, app.TensionmcaniqueEditField);
        end

        % Value changed function: Segmentsx0EditField
        function Segmentsx0EditFieldValueChanged(app, event)
            app.SimulationParams.x_0 = SetParsedValue(app, app.Segmentsx0EditField);
        end

        % Value changed function: Segmentsv0EditField
        function Segmentsv0EditFieldValueChanged(app, event)
            app.SimulationParams.v_0 = SetParsedValue(app, app.Segmentsv0EditField);
        end

        % Value changed function: DistancecapteurcordeEditField
        function DistancecapteurcordeEditFieldValueChanged(app, event)
            app.SimulationParams.position_centre = SetParsedValue(app, app.DistancecapteurcordeEditField);
        end

        % Value changed function: PasdesimulationEditField
        function PasdesimulationEditFieldValueChanged(app, event)
            app.SimulationParams.dt = SetParsedValue(app, app.PasdesimulationEditField);
        end

        % Value changed function: NombredesegmentsEditField
        function NombredesegmentsEditFieldValueChanged(app, event)
            app.SimulationParams.N = SetParsedValue(app, app.NombredesegmentsEditField);
        end

        % Button pushed function: EnregistrerButton
        function EnregistrerButtonPushed(app, event)
            %Create json
            [file,path,~] = uiputfile(['datas ' datestr(now, 'yyyymmddhhMMss') '.mat'], "Choisissez le nom du fichier pour enregistrer les données.");
            if ~isequal(file,0)
                params = app.SimulationParams;
                note = app.CommentaireTextArea.Value;
                results = app.simulatorManager.resultsByParameter;
                save([path file], 'params', 'note', 'results');
            end
        end

        % Button pushed function: ChargerButton
        function ChargerButtonPushed(app, event)
            [file,path] = uigetfile('*.mat', "Sélectionez la simulation à charger.");
            if ~isequal(file,0)
                
                load([path file], 'params', 'note', 'results');
                app.SimulationParams = params;
                app.CommentaireTextArea.Value = note;
                app.simulationDataFormator.Reset();
                app.simulationDataFormator.SetSimulation(app.SimulationParams);
                app.ResultUITable.ColumnName = app.simulationDataFormator.ColumnName;
                set(app.ResultUITable, 'data', {});
                
                app.simulatorManager.LoadResultArray(results);
                UpdateFieldsToSimulationParams(app);                
                
                app.SetStatut("Prêt.");
                app.EnregistrerButton.Enable = true;
                app.ChargerButton.Enable = true;
                app.SimulerButton.Enable = true;
            end
        end

        % Value changed function: GainVoltageForceEditField
        function GainVoltageForceEditFieldValueChanged(app, event)
            app.SimulationParams.actuateur_force_magnetique = SetParsedValue(app, app.GainVoltageForceEditField);
        end

        % Value changed function: InductanceEditField
        function InductanceEditFieldValueChanged(app, event)
            app.SimulationParams.actuateur_circuitRL_s = SetParsedValue(app, app.InductanceEditField);
        end

        % Value changed function: GainCourantVoltageEditField
        function GainCourantVoltageEditFieldValueChanged(app, event)
            app.SimulationParams.capteur_R3 = SetParsedValue(app, app.GainCourantVoltageEditField);
        end

        % Value changed function: CircuitPassehautEditField
        function CircuitPassehautEditFieldValueChanged(app, event)
            app.SimulationParams.circuit_passe_haut = SetParsedValue(app, app.CircuitPassehautEditField);
        end

        % Value changed function: CircuitPassebasEditField
        function CircuitPassebasEditFieldValueChanged(app, event)
            app.SimulationParams.circuit_passe_bas = SetParsedValue(app, app.CircuitPassebasEditField);
        end

        % Value changed function: AccordPEditField
        function AccordPEditFieldValueChanged(app, event)
            app.SimulationParams.regulateur_gain = SetParsedValue(app, app.AccordPEditField);
        end

        % Value changed function: GainTensionAngleEditField
        function GainTensionAngleEditFieldValueChanged(app, event)
            app.SimulationParams.servoclef_clef = SetParsedValue(app, app.GainTensionAngleEditField);
        end

        % Value changed function: TempsdesaisieEditField
        function TempsdesaisieEditFieldValueChanged(app, event)
            app.SimulationParams.freq_mes_transfer = SetParsedValue(app, app.TempsdesaisieEditField);
        end

        % Button pushed function: TensionButton
        function TensionButtonPushed(app, event)
            open_system('SystemeCompletProcedural/Tension_corde');
        end

        % Button pushed function: PositioncordeButton
        function PositioncordeButtonPushed(app, event)
            open_system('SystemeCompletProcedural/Position_corde');
        end

        % Button pushed function: PositioncapteButton
        function PositioncapteButtonPushed(app, event)
            open_system('SystemeCompletProcedural/Position_captée');
        end

        % Button pushed function: Sortie05VButton
        function Sortie05VButtonPushed(app, event)
            open_system('SystemeCompletProcedural/Sortie_0-5V');
        end

        % Button pushed function: FrquenceButton
        function FrquenceButtonPushed(app, event)
            open_system('SystemeCompletProcedural/Freq');
        end

        % Button pushed function: ActuateurButton
        function ActuateurButtonPushed(app, event)
            open_system('SystemeCompletProcedural/Sortie_actuateur');
        end

        % Button pushed function: RgulateurButton
        function RgulateurButtonPushed(app, event)
            open_system('SystemeCompletProcedural/Régulateur scope');
        end

        % Button pushed function: JouerButton
        function JouerButtonPushed(app, event)
            %             UIAxesAnimation;
            %Get first sim.
            try
                idxSim = str2double( app.SimulationDropDown_Animation.Value);
                param = app.simulatorManager.resultsByParameter{idxSim}{1};
                data = app.simulatorManager.resultsByParameter{idxSim}{2}.corde_mesure;
                slowFactor = app.RalentissementEditField.Value;
                
                app.UIAxesAnimation.XLim = [0, param.corde.N];
                %                 ymax = 1.25*max(max(max(abs(data.Data)))); %0.003
                %                 app.UIAxesAnimation.YLim = [-1*ymax, ymax];
                
                app.CordeAnimator.AnimateCorde(app.UIAxesAnimation, data, param.corde.N, param.dt, slowFactor, @() app.flagStopAnimation)
                
                app.flagStopAnimation = false;
            catch ME
                app.SetStatut(strcat("Erreur : Animation de la corde -  ", ME.message));
            end
            
        end

        % Button pushed function: InterrompreButton
        function InterrompreButtonPushed(app, event)
            app.flagStopAnimation = true;
        end

        % Value changed function: SimulationDropDown
        function SimulationDropDownValueChanged(app, event)
            idxSim = str2double(app.SimulationDropDown.Value);
            app.SegmentDropDown.Items = arrayfun(@(x) num2str(x), 1:(app.simulatorManager.resultsByParameter{idxSim}{1}.corde.N), 'UniformOutput',false);
        end

        % Value changed function: SegmentDropDown
        function SegmentDropDownValueChanged(app, event)
            try
                segment = str2double(app.SegmentDropDown.Value);
                idxSim = str2double(app.SimulationDropDown.Value);
                t = app.simulatorManager.resultsByParameter{idxSim}{2}.corde_mesure.Time;
                pos = app.simulatorManager.resultsByParameter{idxSim}{2}.corde_mesure.Data(:,segment);
                fig = app.UIAxesPositionSegment;
                fig.XLim = [app.simulatorManager.resultsByParameter{idxSim}{2}.SimulationMetadata.ModelInfo.StartTime app.simulatorManager.resultsByParameter{idxSim}{2}.SimulationMetadata.ModelInfo.StopTime];
                plot(fig, t, pos,'-');
            catch ME
                app.SetStatut(strcat('Erreur (Affichage de la position) : ', ME.message)) ;
            end
            
        end

        % Value changed function: NoteDropDown
        function NoteDropDownValueChanged(app, event)
            value = app.NoteDropDown.Value;
            switch value
                case 'Si'
                    noteFrq = 123.470825314031;
                case 'Sib (La#)'
                    noteFrq = 116.540940379522;
                case 'La'
                    noteFrq = 110;
                case 'Sol#'
                    noteFrq = 103.826174394986;
                case 'Sol'
                    noteFrq = 97.9988589954373;
                case 'Fa#'
                    noteFrq = 92.4986056779086;
                case 'Fa'
                    noteFrq = 87.307057858251;
                case 'Mi'
                    noteFrq = 82.4068892282175;
                case 'Mib (Ré#)'
                    noteFrq = 77.7817459305202;
                case 'Ré'
                    noteFrq = 73.4161919793519;
                case 'Do#'
                    noteFrq = 69.295657744218;
                case 'Do'
                    noteFrq = 65.4063913251496;
            end
            if(app.NoteOptionSwitch.Value == "Choisir")
                app.SimulationParams.Frequence_final = noteFrq;
            else
                app.SimulationParams.Frequence_final = [app.SimulationParams.Frequence_final noteFrq];
            end
            app.UpdateFieldsToSimulationParams();
        end

        % Value changed function: AutoentretienSwitch
        function AutoentretienSwitchValueChanged(app, event)
            value = app.AutoentretienSwitch.Value;
            if(isempty(value))
                app.SimulationParams.simulationActionParameter.IsAutoEntretienOn = false;
                app.simulatorManager.SetAutoEntretien(false);
            else
                app.simulatorManager.SetAutoEntretien(true);
                app.SimulationParams.simulationActionParameter.IsAutoEntretienOn = true;
            end
        end

        % Value changed function: AutoaccordSwitch
        function AutoaccordSwitchValueChanged(app, event)
            value = app.AutoaccordSwitch.Value;
            if(isempty(value))
                app.simulatorManager.SetAutoAccord(false);
                app.SimulationParams.simulationActionParameter.IsAutoAccordOn = false;
            else
                app.simulatorManager.SetAutoAccord(true);
                app.SimulationParams.simulationActionParameter.IsAutoAccordOn = false;
            end
        end

        % Value changed function: RfrencefrquenceEditField
        function RfrencefrquenceEditFieldValueChanged(app, event)
            app.SimulationParams.clockFreq = SetParsedValue(app, app.RfrencefrquenceEditField);
        end

        % Value changed function: VitessemaximaleEditField
        function VitessemaximaleEditFieldValueChanged(app, event)
            app.SimulationParams.servoClefVitesseMax = SetParsedValue(app, app.VitessemaximaleEditField);
        end

        % Value changed function: TempsderponseEditField
        function TempsderponseEditFieldValueChanged(app, event)
            app.SimulationParams.freq_coupure = SetParsedValue(app, app.TempsderponseEditField);
        end

        % Value changed function: GainAngleTempsdimpulsionEditField
        function GainAngleTempsdimpulsionEditFieldValueChanged(app, event)
            app.SimulationParams.gainVoltageAngle = SetParsedValue(app, app.GainAngleTempsdimpulsionEditField);
        end

        % Value changed function: HystrsisVaEditField
        function HystrsisVaEditFieldValueChanged(app, event)
            app.SimulationParams.Circuit_Va = SetParsedValue(app, app.HystrsisVaEditField);
        end

        % Value changed function: HystrsisVbEditField
        function HystrsisVbEditFieldValueChanged(app, event)
            app.SimulationParams.Circuit_Vb = SetParsedValue(app, app.HystrsisVbEditField);
        end

        % Value changed function: LimitedetensionEditField
        function LimitedetensionEditFieldValueChanged(app, event)
            app.SimulationParams.tensionAjustable = SetParsedValue(app, app.LimitedetensionEditField);
        end

        % Value changed function: GainLumireCourantEditField
        function GainLumireCourantEditFieldValueChanged(app, event)
            app.SimulationParams.capteur_courantMax = SetParsedValue(app, app.GainLumireCourantEditField);
        end

        % Value changed function: AccordIEditField
        function AccordIEditFieldValueChanged(app, event)
            app.SimulationParams.regulateur_accordI = SetParsedValue(app, app.AccordIEditField);
        end

        % Callback function
        function InverserpolarisationCheckBoxValueChanged(app, event)
%             value = app.InverserpolarisationCheckBox.Value;
%             app.SimulationParams.IsPolarisationInverted = value;
        end

        % Value changed function: FrquenceinitialeEditField
        function FrquenceinitialeEditFieldValueChanged(app, event)
            app.SimulationParams.Frequence_initial = app.FrquenceinitialeEditField.Value;
        end

        % Value changed function: TempsconsigneEditField
        function TempsconsigneEditFieldValueChanged(app, event)
            app.SimulationParams.Frequence_time_step = app.TempsconsigneEditField.Value;
        end

        % Value changed function: NoiseOffSetSwitch, NoiseOffsetX
        function NoiseOffSetSwitchValueChanged(app, event)
            value = app.NoiseOffSetSwitch.Value;
            x = app.NoiseOffsetX.Value;
            isOn = (value=="Allumé");
            app.simulatorManager.SetOffSet(isOn, x);
            app.SimulationParams.simulationActionParameter.OffSetIsOn = isOn;
            app.SimulationParams.simulationActionParameter.OffSetValue = x;
        end

        % Value changed function: NoiseSineA, NoiseSineSwitch, 
        % NoiseSineW
        function NoiseSineSwitchValueChanged(app, event)
            value = app.NoiseSineSwitch.Value;
            A = app.NoiseSineA.Value;
            w = app.NoiseSineW.Value;
            isOn = (value=="Allumé");
            app.simulatorManager.SetSineNoise(isOn, w, A);
            app.SimulationParams.simulationActionParameter.SineNoiseA = A;
            app.SimulationParams.simulationActionParameter.SineNoiseIsOn = isOn;
            app.SimulationParams.simulationActionParameter.SineNoiseW = w;
        end

        % Value changed function: NoiseWhiteMean, NoiseWhiteSwitch, 
        % NoiseWhiteVar
        function NoiseWhiteSwitchValueChanged(app, event)
            value = app.NoiseWhiteSwitch.Value;
            var = app.NoiseWhiteVar.Value;
            mean = app.NoiseWhiteMean.Value;
            isOn = (value=="Allumé");
            app.simulatorManager.SetNoiseRandom(isOn,var, mean);
            app.SimulationParams.simulationActionParameter.WhiteNoiseIsOn = isOn;
            app.SimulationParams.simulationActionParameter.WhiteNoiseMean = mean;
            app.SimulationParams.simulationActionParameter.WhiteNoiseVar = var;
        end

        % Value changed function: ManualFrequence, 
        % ManualFrequenceSwitch
        function ManualFrequenceSwitchValueChanged(app, event)
            value = app.ManualFrequenceSwitch.Value;
            freq = app.ManualFrequence.Value;
            isOn = (value == "Entretien Manuel");
            app.simulatorManager.SetManualFrequence(isOn,freq);
            app.SimulationParams.simulationActionParameter.ManualFreqIsOn = isOn;
            app.SimulationParams.simulationActionParameter.ManualFreqValue = freq;
        end

        % Value changed function: PerformanceCritreDropDown, 
        % PerformanceParamtreDropDown
        function PerformanceParamtreDropDownValueChanged(app, event)
            param = app.PerformanceParamtreDropDown.Value;
            criteria = app.PerformanceCritreDropDown.Value;
            app.performanceGraphManager.UpdateGraphHandler(app.UIAxesPerformance, param, criteria);
            if(~isempty(app.performanceGraphManager.groupListStr))
                app.LigneDropDown.Items = app.performanceGraphManager.groupListStr;
                app.LigneDropDown.Value = app.LigneDropDown.Items{1};
                app.LigneDropDown.Visible = true;
                app.LigneDropDownValueChanged(event);
            end
        end

        % Value changed function: HarmoniqueSwitch
        function HarmoniqueSpinnerValueChanged(app, event)
            app.SimulationParams.Harmonique = str2double( app.HarmoniqueSwitch.Value);    
            app.SimulationParams.IsPolarisationInverted = app.SimulationParams.Harmonique == 2;
        end

        % Value changed function: LigneDropDown
        function LigneDropDownValueChanged(app, event)
            if(isempty(app.LigneDropDown.Value))
                return;
            end
            value = str2double(app.LigneDropDown.Value);
            line = app.performanceGraphManager.MapLineById(value);
            
            for i = 1:length(app.UIAxesPerformance.Children)
                l = app.UIAxesPerformance.Children(i);
                set(l,'LineStyle','-');
                set(l,'LineWidth',0.5);
            end
            set(line,'LineStyle','--');
            set(line,'LineWidth',1);
            rows = {};
            for i = 1:length(app.performanceGraphManager.uniqueParameterLabel)
                rows = [rows; app.performanceGraphManager.uniqueParameterLabel(i) app.performanceGraphManager.uniqueParameter(value,i)];
            end
            set(app.PerformanceParameterGroupTable, 'data', rows)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InterfaceSimulateurUIFigure = uifigure('Visible', 'off');
            app.InterfaceSimulateurUIFigure.Name = 'Interface Simulateur';
            app.ResultGroup = uitabgroup(app.InterfaceSimulateurUIFigure);
            app.RsultatsTab = uitab(app.ResultGroup);
            app.RsultatsTab.Title = 'Résultats';
            app.ResultUITable = uitable(app.RsultatsTab);
            app.ResultUITable.ColumnName = {'Id'; 'Succès'; 'Temps'; 'Justesse'; 'Vitesse d''accord'};
            app.ResultUITable.RowName = {''};
            app.OscilloscopeTab = uitab(app.ResultGroup);
            app.OscilloscopeTab.Title = 'Oscilloscope';
            app.TensionButton = uibutton(app.OscilloscopeTab, 'push');
            app.TensionButton.ButtonPushedFcn = createCallbackFcn(app, @TensionButtonPushed, true);
            app.TensionButton.Text = 'Tension';
            app.PositioncordeButton = uibutton(app.OscilloscopeTab, 'push');
            app.PositioncordeButton.ButtonPushedFcn = createCallbackFcn(app, @PositioncordeButtonPushed, true);
            app.PositioncordeButton.Text = 'Position corde';
            app.PositioncapteButton = uibutton(app.OscilloscopeTab, 'push');
            app.PositioncapteButton.ButtonPushedFcn = createCallbackFcn(app, @PositioncapteButtonPushed, true);
            app.PositioncapteButton.Text = 'Position captée';
            app.Sortie05VButton = uibutton(app.OscilloscopeTab, 'push');
            app.Sortie05VButton.ButtonPushedFcn = createCallbackFcn(app, @Sortie05VButtonPushed, true);
            app.Sortie05VButton.Text = 'Sortie 0-5V';
            app.FrquenceButton = uibutton(app.OscilloscopeTab, 'push');
            app.FrquenceButton.ButtonPushedFcn = createCallbackFcn(app, @FrquenceButtonPushed, true);
            app.FrquenceButton.Text = 'Fréquence';
            app.ActuateurButton = uibutton(app.OscilloscopeTab, 'push');
            app.ActuateurButton.ButtonPushedFcn = createCallbackFcn(app, @ActuateurButtonPushed, true);
            app.ActuateurButton.Text = 'Actuateur';
            app.RgulateurButton = uibutton(app.OscilloscopeTab, 'push');
            app.RgulateurButton.ButtonPushedFcn = createCallbackFcn(app, @RgulateurButtonPushed, true);
            app.RgulateurButton.Text = 'Régulateur';
            app.AnimationdelacordeTab = uitab(app.ResultGroup);
            app.AnimationdelacordeTab.Title = 'Animation de la corde';
            app.UIAxesAnimation = uiaxes(app.AnimationdelacordeTab);
            title(app.UIAxesAnimation, 'Animation de la corde')
            xlabel(app.UIAxesAnimation, 'Segment')
            ylabel(app.UIAxesAnimation, 'Position')
            app.UIAxesAnimation.XLim = [0 1];
            app.UIAxesAnimation.YLim = [-0.003 0.003];
            app.JouerButton = uibutton(app.AnimationdelacordeTab, 'push');
            app.JouerButton.ButtonPushedFcn = createCallbackFcn(app, @JouerButtonPushed, true);
            app.JouerButton.Text = 'Jouer';
            app.InterrompreButton = uibutton(app.AnimationdelacordeTab, 'push');
            app.InterrompreButton.ButtonPushedFcn = createCallbackFcn(app, @InterrompreButtonPushed, true);
            app.InterrompreButton.Text = 'Interrompre';
            app.RalentissementEditFieldLabel = uilabel(app.AnimationdelacordeTab);
            app.RalentissementEditFieldLabel.Text = 'Ralentissement';
            app.RalentissementEditField = uieditfield(app.AnimationdelacordeTab, 'text');
            app.RalentissementEditField.Value = '100';
            app.SimulationDropDown_2Label = uilabel(app.AnimationdelacordeTab);
            app.SimulationDropDown_2Label.Text = 'Simulation #';
            app.SimulationDropDown_Animation = uidropdown(app.AnimationdelacordeTab);
            app.SimulationDropDown_Animation.Items = {'1', '2', '3', '4'};
            app.SimulationDropDown_Animation.Value = '1';
            app.PositiondunsegmentTab = uitab(app.ResultGroup);
            app.PositiondunsegmentTab.Title = 'Position d''un segment';
            app.SegmentDropDownLabel = uilabel(app.PositiondunsegmentTab);
            app.SegmentDropDownLabel.Text = 'Segment #';
            app.SegmentDropDown = uidropdown(app.PositiondunsegmentTab);
            app.SegmentDropDown.Items = {'1', '2', '3'};
            app.SegmentDropDown.ValueChangedFcn = createCallbackFcn(app, @SegmentDropDownValueChanged, true);
            app.SegmentDropDown.Value = '1';
            app.SimulationDropDownLabel = uilabel(app.PositiondunsegmentTab);
            app.SimulationDropDownLabel.Text = 'Simulation #';
            app.SimulationDropDown = uidropdown(app.PositiondunsegmentTab);
            app.SimulationDropDown.Items = {'1', '2', '3', '4'};
            app.SimulationDropDown.ValueChangedFcn = createCallbackFcn(app, @SimulationDropDownValueChanged, true);
            app.SimulationDropDown.Value = '1';
            app.UIAxesPositionSegment = uiaxes(app.PositiondunsegmentTab);
            title(app.UIAxesPositionSegment, 'Animation de la corde')
            xlabel(app.UIAxesPositionSegment, 'Segment')
            ylabel(app.UIAxesPositionSegment, 'Position')
            app.UIAxesPositionSegment.XLim = [0 1];
            app.UIAxesPositionSegment.YLim = [-0.003 0.003];
            app.PerformanceTab = uitab(app.ResultGroup);
            app.PerformanceTab.Title = 'Performance';
            app.UIAxesPerformance = uiaxes(app.PerformanceTab);
            title(app.UIAxesPerformance, 'Performance')
            xlabel(app.UIAxesPerformance, 'X')
            ylabel(app.UIAxesPerformance, 'Y')
            app.ParamtreDropDownLabel = uilabel(app.PerformanceTab);
            app.ParamtreDropDownLabel.Text = 'Paramètre';
            app.PerformanceParamtreDropDown = uidropdown(app.PerformanceTab);
            app.PerformanceParamtreDropDown.ValueChangedFcn = createCallbackFcn(app, @PerformanceParamtreDropDownValueChanged, true);
            app.CritreDropDownLabel = uilabel(app.PerformanceTab);
            app.CritreDropDownLabel.Text = 'Critère';
            app.PerformanceCritreDropDown = uidropdown(app.PerformanceTab);
            app.PerformanceCritreDropDown.ValueChangedFcn = createCallbackFcn(app, @PerformanceParamtreDropDownValueChanged, true);
            app.PerformanceParameterGroupTable = uitable(app.PerformanceTab);
            app.PerformanceParameterGroupTable.ColumnName = {'Paramètre'; 'Valeur'};
            app.PerformanceParameterGroupTable.RowName = {};
            app.LigneDropDownLabel = uilabel(app.PerformanceTab);
            app.LigneDropDownLabel.Text = 'Ligne';
            app.LigneDropDown = uidropdown(app.PerformanceTab);
            app.LigneDropDown.ValueChangedFcn = createCallbackFcn(app, @LigneDropDownValueChanged, true);
            app.LesAccords2Label = uilabel(app.InterfaceSimulateurUIFigure);
            app.LesAccords2Label.Text = 'Les Accords2';
            app.SimulerButton = uibutton(app.InterfaceSimulateurUIFigure, 'push');
            app.SimulerButton.ButtonPushedFcn = createCallbackFcn(app, @Simulate, true);
            app.SimulerButton.Text = 'Simuler';
            app.EnregistrerButton = uibutton(app.InterfaceSimulateurUIFigure, 'push');
            app.EnregistrerButton.ButtonPushedFcn = createCallbackFcn(app, @EnregistrerButtonPushed, true);
            app.EnregistrerButton.Enable = 'off';
            app.EnregistrerButton.Text = 'Enregistrer';
            app.ChargerButton = uibutton(app.InterfaceSimulateurUIFigure, 'push');
            app.ChargerButton.ButtonPushedFcn = createCallbackFcn(app, @ChargerButtonPushed, true);
            app.ChargerButton.Enable = 'off';
            app.ChargerButton.Text = 'Charger';
            app.RinitialiserButton = uibutton(app.InterfaceSimulateurUIFigure, 'push');
            app.RinitialiserButton.ButtonPushedFcn = createCallbackFcn(app, @Reset, true);
            app.RinitialiserButton.Text = 'Réinitialiser';
            app.StatutLabel = uilabel(app.InterfaceSimulateurUIFigure);
            app.StatutLabel.Text = 'Statut';
            app.ParameterGroup = uitabgroup(app.InterfaceSimulateurUIFigure);
            app.ActionTab = uitab(app.ParameterGroup);
            app.ActionTab.Title = 'Action';
            app.AutoentretienSwitchLabel = uilabel(app.ActionTab);
            app.AutoentretienSwitchLabel.Text = 'Auto-entretien';
            app.AutoentretienSwitch = uiswitch(app.ActionTab, 'slider');
            app.AutoentretienSwitch.Items = {'', 'Allumé'};
            app.AutoentretienSwitch.ValueChangedFcn = createCallbackFcn(app, @AutoentretienSwitchValueChanged, true);
            app.AutoentretienSwitch.Value = '';
            app.AutoaccordSwitchLabel = uilabel(app.ActionTab);
            app.AutoaccordSwitchLabel.Text = 'Auto-accord';
            app.AutoaccordSwitch = uiswitch(app.ActionTab, 'slider');
            app.AutoaccordSwitch.Items = {'', 'Allumé'};
            app.AutoaccordSwitch.ValueChangedFcn = createCallbackFcn(app, @AutoaccordSwitchValueChanged, true);
            app.AutoaccordSwitch.Value = '';
            app.PerturbationOffsetPanel = uipanel(app.ActionTab);
            app.PerturbationOffsetPanel.Title = 'Perturbation : Offset';
            app.NoiseOffSetSwitch = uiswitch(app.PerturbationOffsetPanel, 'slider');
            app.NoiseOffSetSwitch.Items = {'', 'Allumé'};
            app.NoiseOffSetSwitch.ValueChangedFcn = createCallbackFcn(app, @NoiseOffSetSwitchValueChanged, true);
            app.NoiseOffSetSwitch.Value = '';
            app.NoiseOffsetX = uieditfield(app.PerturbationOffsetPanel, 'numeric');
            app.NoiseOffsetX.ValueChangedFcn = createCallbackFcn(app, @NoiseOffSetSwitchValueChanged, true);
            app.PerturbationSinePanel = uipanel(app.ActionTab);
            app.PerturbationSinePanel.Title = 'Perturbation : Sine';
            app.NoiseSineSwitch = uiswitch(app.PerturbationSinePanel, 'slider');
            app.NoiseSineSwitch.Items = {'', 'Allumé'};
            app.NoiseSineSwitch.ValueChangedFcn = createCallbackFcn(app, @NoiseSineSwitchValueChanged, true);
            app.NoiseSineSwitch.Value = '';
            app.AEditFieldLabel = uilabel(app.PerturbationSinePanel);
            app.AEditFieldLabel.Text = 'A';
            app.NoiseSineA = uieditfield(app.PerturbationSinePanel, 'numeric');
            app.NoiseSineA.ValueChangedFcn = createCallbackFcn(app, @NoiseSineSwitchValueChanged, true);
            app.wEditFieldLabel = uilabel(app.PerturbationSinePanel);
            app.wEditFieldLabel.Text = 'w';
            app.NoiseSineW = uieditfield(app.PerturbationSinePanel, 'numeric');
            app.NoiseSineW.Limits = [0 Inf];
            app.NoiseSineW.ValueChangedFcn = createCallbackFcn(app, @NoiseSineSwitchValueChanged, true);
            app.PerturbationBruitblancPanel = uipanel(app.ActionTab);
            app.PerturbationBruitblancPanel.Title = 'Perturbation : Bruit blanc';
            app.NoiseWhiteSwitch = uiswitch(app.PerturbationBruitblancPanel, 'slider');
            app.NoiseWhiteSwitch.Items = {'', 'Allumé'};
            app.NoiseWhiteSwitch.ValueChangedFcn = createCallbackFcn(app, @NoiseWhiteSwitchValueChanged, true);
            app.NoiseWhiteSwitch.Value = '';
            app.varLabel = uilabel(app.PerturbationBruitblancPanel);
            app.varLabel.Text = 'var';
            app.NoiseWhiteVar = uieditfield(app.PerturbationBruitblancPanel, 'numeric');
            app.NoiseWhiteVar.Limits = [0 Inf];
            app.NoiseWhiteVar.ValueChangedFcn = createCallbackFcn(app, @NoiseWhiteSwitchValueChanged, true);
            app.MeanLabel = uilabel(app.PerturbationBruitblancPanel);
            app.MeanLabel.Text = 'Mean';
            app.NoiseWhiteMean = uieditfield(app.PerturbationBruitblancPanel, 'numeric');
            app.NoiseWhiteMean.ValueChangedFcn = createCallbackFcn(app, @NoiseWhiteSwitchValueChanged, true);
            app.ManualFrequenceSwitch = uiswitch(app.ActionTab, 'toggle');
            app.ManualFrequenceSwitch.Items = {'Consigne', 'Entretien Manuel'};
            app.ManualFrequenceSwitch.ValueChangedFcn = createCallbackFcn(app, @ManualFrequenceSwitchValueChanged, true);
            app.ManualFrequenceSwitch.Value = 'Consigne';
            app.FrquenceEditFieldLabel = uilabel(app.ActionTab);
            app.FrquenceEditFieldLabel.Tooltip = {'Hz'};
            app.FrquenceEditFieldLabel.Text = 'Fréquence';
            app.ManualFrequence = uieditfield(app.ActionTab, 'numeric');
            app.ManualFrequence.Limits = [0 Inf];
            app.ManualFrequence.ValueChangedFcn = createCallbackFcn(app, @ManualFrequenceSwitchValueChanged, true);
            app.ManualFrequence.Tooltip = {'Hz'};
            app.CordeTab = uitab(app.ParameterGroup);
            app.CordeTab.Title = 'Corde';
            app.MasseliniqueEditFieldLabel = uilabel(app.CordeTab);
            app.MasseliniqueEditFieldLabel.Tooltip = {'Kg/m'};
            app.MasseliniqueEditFieldLabel.Text = 'Masse linéique';
            app.MasseliniqueEditField = uieditfield(app.CordeTab, 'text');
            app.MasseliniqueEditField.ValueChangedFcn = createCallbackFcn(app, @EditMassLineique, true);
            app.LongueurEditFieldLabel = uilabel(app.CordeTab);
            app.LongueurEditFieldLabel.Tooltip = {'m'};
            app.LongueurEditFieldLabel.Text = 'Longueur';
            app.LongueurEditField = uieditfield(app.CordeTab, 'text');
            app.LongueurEditField.ValueChangedFcn = createCallbackFcn(app, @EditLongueur, true);
            app.FrottementEditFieldLabel = uilabel(app.CordeTab);
            app.FrottementEditFieldLabel.Tooltip = {'Kg/s'};
            app.FrottementEditFieldLabel.Text = 'Frottement';
            app.FrottementEditField = uieditfield(app.CordeTab, 'text');
            app.FrottementEditField.ValueChangedFcn = createCallbackFcn(app, @FrottementEditFieldValueChanged, true);
            app.TensionmcaniqueEditFieldLabel = uilabel(app.CordeTab);
            app.TensionmcaniqueEditFieldLabel.Tooltip = {'N'};
            app.TensionmcaniqueEditFieldLabel.Text = 'Tension mécanique';
            app.TensionmcaniqueEditField = uieditfield(app.CordeTab, 'text');
            app.TensionmcaniqueEditField.ValueChangedFcn = createCallbackFcn(app, @TensionmcaniqueEditFieldValueChanged, true);
            app.Segmentsx0EditFieldLabel = uilabel(app.CordeTab);
            app.Segmentsx0EditFieldLabel.Tooltip = {'m'};
            app.Segmentsx0EditFieldLabel.Text = 'Segments x0';
            app.Segmentsx0EditField = uieditfield(app.CordeTab, 'text');
            app.Segmentsx0EditField.ValueChangedFcn = createCallbackFcn(app, @Segmentsx0EditFieldValueChanged, true);
            app.Segmentsv0EditFieldLabel = uilabel(app.CordeTab);
            app.Segmentsv0EditFieldLabel.Tooltip = {'m/s'};
            app.Segmentsv0EditFieldLabel.Text = 'Segments v0';
            app.Segmentsv0EditField = uieditfield(app.CordeTab, 'text');
            app.Segmentsv0EditField.ValueChangedFcn = createCallbackFcn(app, @Segmentsv0EditFieldValueChanged, true);
            app.Segmentsv0EditField.Tooltip = {''};
            app.ValeursphysiquesLabel = uilabel(app.CordeTab);
            app.ValeursphysiquesLabel.Text = 'Valeurs physiques';
            app.ValeursinitialesLabel = uilabel(app.CordeTab);
            app.ValeursinitialesLabel.Text = 'Valeurs initiales';
            app.ConfigurationTab = uitab(app.ParameterGroup);
            app.ConfigurationTab.Title = 'Configuration';
            app.PositionrelativeducapteurLabel = uilabel(app.ConfigurationTab);
            app.PositionrelativeducapteurLabel.Tooltip = {'m'};
            app.PositionrelativeducapteurLabel.Text = {'Position relative'; ' du capteur'};
            app.PositionrelativeducapteurEditField = uieditfield(app.ConfigurationTab, 'text');
            app.PositionrelativeducapteurEditField.ValueChangedFcn = createCallbackFcn(app, @EditPositionCapteur, true);
            app.PositionrelativedelactuateurEditFieldLabel = uilabel(app.ConfigurationTab);
            app.PositionrelativedelactuateurEditFieldLabel.Tooltip = {'m'};
            app.PositionrelativedelactuateurEditFieldLabel.Text = {'Position relative'; ' de l''actuateur'};
            app.PositionrelativedelactuateurEditField = uieditfield(app.ConfigurationTab, 'text');
            app.PositionrelativedelactuateurEditField.ValueChangedFcn = createCallbackFcn(app, @EditPositionActuateur, true);
            app.DistancecapteurcordeEditFieldLabel = uilabel(app.ConfigurationTab);
            app.DistancecapteurcordeEditFieldLabel.Tooltip = {'m'};
            app.DistancecapteurcordeEditFieldLabel.Text = {'Distance '; 'capteur-corde'};
            app.DistancecapteurcordeEditField = uieditfield(app.ConfigurationTab, 'text');
            app.DistancecapteurcordeEditField.ValueChangedFcn = createCallbackFcn(app, @DistancecapteurcordeEditFieldValueChanged, true);
            app.AccordPEditFieldLabel = uilabel(app.ConfigurationTab);
            app.AccordPEditFieldLabel.Tooltip = {'s^2'};
            app.AccordPEditFieldLabel.Text = 'Accord P';
            app.AccordPEditField = uieditfield(app.ConfigurationTab, 'text');
            app.AccordPEditField.ValueChangedFcn = createCallbackFcn(app, @AccordPEditFieldValueChanged, true);
            app.AccordIEditFieldLabel = uilabel(app.ConfigurationTab);
            app.AccordIEditFieldLabel.Tooltip = {'s^2'};
            app.AccordIEditFieldLabel.Text = 'Accord I';
            app.AccordIEditField = uieditfield(app.ConfigurationTab, 'text');
            app.AccordIEditField.ValueChangedFcn = createCallbackFcn(app, @AccordIEditFieldValueChanged, true);
            app.PositionnementLabel = uilabel(app.ConfigurationTab);
            app.PositionnementLabel.Text = 'Positionnement';
            app.AccordeurLabel = uilabel(app.ConfigurationTab);
            app.AccordeurLabel.Text = 'Accordeur';
            app.HarmoniqueSwitchLabel = uilabel(app.ConfigurationTab);
            app.HarmoniqueSwitchLabel.Text = 'Harmonique';
            app.HarmoniqueSwitch = uiswitch(app.ConfigurationTab, 'slider');
            app.HarmoniqueSwitch.Items = {'1', '2'};
            app.HarmoniqueSwitch.ValueChangedFcn = createCallbackFcn(app, @HarmoniqueSpinnerValueChanged, true);
            app.HarmoniqueSwitch.Value = '1';
            app.AvancTab = uitab(app.ParameterGroup);
            app.AvancTab.Title = 'Avancé';
            app.PasdesimulationEditFieldLabel = uilabel(app.AvancTab);
            app.PasdesimulationEditFieldLabel.Tooltip = {'s'};
            app.PasdesimulationEditFieldLabel.Text = {'Pas de '; 'simulation'};
            app.PasdesimulationEditField = uieditfield(app.AvancTab, 'text');
            app.PasdesimulationEditField.ValueChangedFcn = createCallbackFcn(app, @PasdesimulationEditFieldValueChanged, true);
            app.NombredesegmentsLabel = uilabel(app.AvancTab);
            app.NombredesegmentsLabel.Text = {'Nombre '; 'de segments'};
            app.NombredesegmentsEditField = uieditfield(app.AvancTab, 'text');
            app.NombredesegmentsEditField.ValueChangedFcn = createCallbackFcn(app, @NombredesegmentsEditFieldValueChanged, true);
            app.GainTensionAngleEditFieldLabel = uilabel(app.AvancTab);
            app.GainTensionAngleEditFieldLabel.Tooltip = {'N/rad'};
            app.GainTensionAngleEditFieldLabel.Text = 'Gain Tension/Angle';
            app.GainTensionAngleEditField = uieditfield(app.AvancTab, 'text');
            app.GainTensionAngleEditField.ValueChangedFcn = createCallbackFcn(app, @GainTensionAngleEditFieldValueChanged, true);
            app.TempsdesaisieEditFieldLabel = uilabel(app.AvancTab);
            app.TempsdesaisieEditFieldLabel.Tooltip = {'s'};
            app.TempsdesaisieEditFieldLabel.Text = 'Temps de saisie';
            app.TempsdesaisieEditField = uieditfield(app.AvancTab, 'text');
            app.TempsdesaisieEditField.ValueChangedFcn = createCallbackFcn(app, @TempsdesaisieEditFieldValueChanged, true);
            app.RfrencefrquenceEditFieldLabel = uilabel(app.AvancTab);
            app.RfrencefrquenceEditFieldLabel.Tooltip = {'Hz'};
            app.RfrencefrquenceEditFieldLabel.Text = 'Référence fréquence';
            app.RfrencefrquenceEditField = uieditfield(app.AvancTab, 'text');
            app.RfrencefrquenceEditField.ValueChangedFcn = createCallbackFcn(app, @RfrencefrquenceEditFieldValueChanged, true);
            app.VitessemaximaleEditFieldLabel = uilabel(app.AvancTab);
            app.VitessemaximaleEditFieldLabel.Tooltip = {'rad/s'};
            app.VitessemaximaleEditFieldLabel.Text = 'Vitesse maximale';
            app.VitessemaximaleEditField = uieditfield(app.AvancTab, 'text');
            app.VitessemaximaleEditField.ValueChangedFcn = createCallbackFcn(app, @VitessemaximaleEditFieldValueChanged, true);
            app.TempsderponseEditFieldLabel = uilabel(app.AvancTab);
            app.TempsderponseEditFieldLabel.Tooltip = {'s'};
            app.TempsderponseEditFieldLabel.Text = 'Temps de réponse';
            app.TempsderponseEditField = uieditfield(app.AvancTab, 'text');
            app.TempsderponseEditField.ValueChangedFcn = createCallbackFcn(app, @TempsderponseEditFieldValueChanged, true);
            app.GainAngleTempsdimpulsionEditFieldLabel = uilabel(app.AvancTab);
            app.GainAngleTempsdimpulsionEditFieldLabel.Tooltip = {'rad/s'};
            app.GainAngleTempsdimpulsionEditFieldLabel.Text = {'Gain Angle/Temps'; 'd''impulsion'};
            app.GainAngleTempsdimpulsionEditField = uieditfield(app.AvancTab, 'text');
            app.GainAngleTempsdimpulsionEditField.ValueChangedFcn = createCallbackFcn(app, @GainAngleTempsdimpulsionEditFieldValueChanged, true);
            app.CordeLabel = uilabel(app.AvancTab);
            app.CordeLabel.Text = 'Corde';
            app.MesuredefrquenceLabel = uilabel(app.AvancTab);
            app.MesuredefrquenceLabel.Text = 'Mesure de fréquence';
            app.ServomoteurLabel = uilabel(app.AvancTab);
            app.ServomoteurLabel.Text = 'Servomoteur';
            app.ClefLabel = uilabel(app.AvancTab);
            app.ClefLabel.Text = 'Clef';
            app.CircuitTab = uitab(app.ParameterGroup);
            app.CircuitTab.Title = 'Circuit';
            app.HystrsisVaEditFieldLabel = uilabel(app.CircuitTab);
            app.HystrsisVaEditFieldLabel.Tooltip = {'V'};
            app.HystrsisVaEditFieldLabel.Text = 'Hystérésis Va';
            app.HystrsisVaEditField = uieditfield(app.CircuitTab, 'text');
            app.HystrsisVaEditField.ValueChangedFcn = createCallbackFcn(app, @HystrsisVaEditFieldValueChanged, true);
            app.HystrsisVbEditFieldLabel = uilabel(app.CircuitTab);
            app.HystrsisVbEditFieldLabel.Tooltip = {'V'};
            app.HystrsisVbEditFieldLabel.Text = 'Hystérésis Vb';
            app.HystrsisVbEditField = uieditfield(app.CircuitTab, 'text');
            app.HystrsisVbEditField.ValueChangedFcn = createCallbackFcn(app, @HystrsisVbEditFieldValueChanged, true);
            app.LimitedetensionEditFieldLabel = uilabel(app.CircuitTab);
            app.LimitedetensionEditFieldLabel.Tooltip = {'V'};
            app.LimitedetensionEditFieldLabel.Text = 'Limite de tension';
            app.LimitedetensionEditField = uieditfield(app.CircuitTab, 'text');
            app.LimitedetensionEditField.ValueChangedFcn = createCallbackFcn(app, @LimitedetensionEditFieldValueChanged, true);
            app.GainLumireCourantEditFieldLabel = uilabel(app.CircuitTab);
            app.GainLumireCourantEditFieldLabel.Tooltip = {'A/Lux'};
            app.GainLumireCourantEditFieldLabel.Text = 'Gain Lumière/Courant';
            app.GainLumireCourantEditField = uieditfield(app.CircuitTab, 'text');
            app.GainLumireCourantEditField.ValueChangedFcn = createCallbackFcn(app, @GainLumireCourantEditFieldValueChanged, true);
            app.CircuitPassebasEditFieldLabel = uilabel(app.CircuitTab);
            app.CircuitPassebasEditFieldLabel.Text = 'Circuit Passe-bas';
            app.CircuitPassebasEditField = uieditfield(app.CircuitTab, 'text');
            app.CircuitPassebasEditField.ValueChangedFcn = createCallbackFcn(app, @CircuitPassebasEditFieldValueChanged, true);
            app.CircuitPassehautEditFieldLabel = uilabel(app.CircuitTab);
            app.CircuitPassehautEditFieldLabel.Text = 'Circuit Passe-haut';
            app.CircuitPassehautEditField = uieditfield(app.CircuitTab, 'text');
            app.CircuitPassehautEditField.ValueChangedFcn = createCallbackFcn(app, @CircuitPassehautEditFieldValueChanged, true);
            app.GainVoltageForceEditFieldLabel = uilabel(app.CircuitTab);
            app.GainVoltageForceEditFieldLabel.Tooltip = {'N/V'};
            app.GainVoltageForceEditFieldLabel.Text = 'Gain Voltage/Force';
            app.GainVoltageForceEditField = uieditfield(app.CircuitTab, 'text');
            app.GainVoltageForceEditField.ValueChangedFcn = createCallbackFcn(app, @GainVoltageForceEditFieldValueChanged, true);
            app.InductanceEditFieldLabel = uilabel(app.CircuitTab);
            app.InductanceEditFieldLabel.Tooltip = {'mH'};
            app.InductanceEditFieldLabel.Text = 'Inductance';
            app.InductanceEditField = uieditfield(app.CircuitTab, 'text');
            app.InductanceEditField.ValueChangedFcn = createCallbackFcn(app, @InductanceEditFieldValueChanged, true);
            app.GainCourantVoltageEditFieldLabel = uilabel(app.CircuitTab);
            app.GainCourantVoltageEditFieldLabel.Tooltip = {'S'};
            app.GainCourantVoltageEditFieldLabel.Text = {'Gain '; 'Courant/Voltage'};
            app.GainCourantVoltageEditField = uieditfield(app.CircuitTab, 'text');
            app.GainCourantVoltageEditField.ValueChangedFcn = createCallbackFcn(app, @GainCourantVoltageEditFieldValueChanged, true);
            app.ActuateurLabel = uilabel(app.CircuitTab);
            app.ActuateurLabel.Text = 'Actuateur';
            app.CapteurLabel = uilabel(app.CircuitTab);
            app.CapteurLabel.Text = 'Capteur';
            app.CircuitLabel = uilabel(app.CircuitTab);
            app.CircuitLabel.Text = 'Circuit';
            app.NotesTab = uitab(app.ParameterGroup);
            app.NotesTab.Title = 'Notes';
            app.CommentaireTextAreaLabel = uilabel(app.NotesTab);
            app.CommentaireTextAreaLabel.Text = 'Commentaire';
            app.CommentaireTextArea = uitextarea(app.NotesTab);
            app.HiddenGroup = uitabgroup(app.InterfaceSimulateurUIFigure);
            app.HiddenGroup.Visible = 'off';
            app.hiddenTab = uitab(app.HiddenGroup);
            app.hiddenTab.Title = 'hidden';
            app.FrquencedemandeHzEditFieldLabel = uilabel(app.InterfaceSimulateurUIFigure);
            app.FrquencedemandeHzEditFieldLabel.Text = {'Fréquence '; 'demandée (Hz)'};
            app.FrquencedemandeHzEditField = uieditfield(app.InterfaceSimulateurUIFigure, 'text');
            app.FrquencedemandeHzEditField.ValueChangedFcn = createCallbackFcn(app, @EditFrequence, true);
            app.DuredesimulationEditFieldLabel = uilabel(app.InterfaceSimulateurUIFigure);
            app.DuredesimulationEditFieldLabel.Tooltip = {'s'};
            app.DuredesimulationEditFieldLabel.Text = {'Durée de '; 'simulation'};
            app.DuredesimulationEditField = uieditfield(app.InterfaceSimulateurUIFigure, 'numeric');
            app.DuredesimulationEditField.Limits = [0 Inf];
            app.DuredesimulationEditField.ValueChangedFcn = createCallbackFcn(app, @EditDuree, true);
            app.NoteOptionSwitch = uiswitch(app.InterfaceSimulateurUIFigure, 'toggle');
            app.NoteOptionSwitch.Items = {'Choisir', 'Ajouter'};
            app.NoteOptionSwitch.Value = 'Choisir';
            app.NoteDropDownLabel = uilabel(app.InterfaceSimulateurUIFigure);
            app.NoteDropDownLabel.Text = 'Note';
            app.NoteDropDown = uidropdown(app.InterfaceSimulateurUIFigure);
            app.NoteDropDown.Items = {'Si', 'Sib (La#)', 'La', 'Sol#', 'Sol', 'Fa#', 'Fa', 'Mi', 'Mib (Ré#)', 'Ré', 'Do#', 'Do'};
            app.NoteDropDown.ValueChangedFcn = createCallbackFcn(app, @NoteDropDownValueChanged, true);
            app.NoteDropDown.Value = 'Do';
            app.FrquenceinitialeLabel = uilabel(app.InterfaceSimulateurUIFigure);
            app.FrquenceinitialeLabel.Tooltip = {'Hz'};
            app.FrquenceinitialeLabel.Text = {'Fréquence'; 'initiale'};
            app.FrquenceinitialeEditField = uieditfield(app.InterfaceSimulateurUIFigure, 'numeric');
            app.FrquenceinitialeEditField.Limits = [0 Inf];
            app.FrquenceinitialeEditField.ValueChangedFcn = createCallbackFcn(app, @FrquenceinitialeEditFieldValueChanged, true);
            app.TempsconsigneEditFieldLabel = uilabel(app.InterfaceSimulateurUIFigure);
            app.TempsconsigneEditFieldLabel.Tooltip = {'s'};
            app.TempsconsigneEditFieldLabel.Text = 'Temps consigne';
            app.TempsconsigneEditField = uieditfield(app.InterfaceSimulateurUIFigure, 'numeric');
            app.TempsconsigneEditField.Limits = [0 Inf];
            app.TempsconsigneEditField.ValueChangedFcn = createCallbackFcn(app, @TempsconsigneEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.InterfaceSimulateurUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InterfaceSimulateurUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InterfaceSimulateurUIFigure)
        end
    end
end