classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ResultGroup                     matlab.ui.container.TabGroup
        RsultatsTab                     matlab.ui.container.Tab
        ResultUITable                   matlab.ui.control.Table
        ScopeTab                        matlab.ui.container.Tab
        TensionButton                   matlab.ui.control.Button
        PositioncordeButton             matlab.ui.control.Button
        PositioncapteButton             matlab.ui.control.Button
        Sortie05VButton                 matlab.ui.control.Button
        FrquenceButton                  matlab.ui.control.Button
        ActuateurButton                 matlab.ui.control.Button
        RgulateurButton                 matlab.ui.control.Button
        AnimationdelacordeTab           matlab.ui.container.Tab
        UIAxesAnimation                 matlab.ui.control.UIAxes
        JouerButton                     matlab.ui.control.Button
        InterrompreButton               matlab.ui.control.Button
        RalentissementEditFieldLabel    matlab.ui.control.Label
        RalentissementEditField         matlab.ui.control.EditField
        SimulationDropDown_2Label       matlab.ui.control.Label
        SimulationDropDown_Animation    matlab.ui.control.DropDown
        PositionsegmentTab              matlab.ui.container.Tab
        SegmentDropDownLabel            matlab.ui.control.Label
        SegmentDropDown                 matlab.ui.control.DropDown
        SimulationDropDownLabel         matlab.ui.control.Label
        SimulationDropDown              matlab.ui.control.DropDown
        UIAxesPositionSegment           matlab.ui.control.UIAxes
        LesAccords2Label                matlab.ui.control.Label
        SimulerButton                   matlab.ui.control.Button
        EnregistrerButton               matlab.ui.control.Button
        ChargerButton                   matlab.ui.control.Button
        RinitialiserButton              matlab.ui.control.Button
        StatutLabel                     matlab.ui.control.Label
        ParameterGroup                  matlab.ui.container.TabGroup
        SimulationTab                   matlab.ui.container.Tab
        DuredesimulationEditFieldLabel  matlab.ui.control.Label
        DuredesimulationEditField       matlab.ui.control.NumericEditField
        NoteOptionSwitch                matlab.ui.control.ToggleSwitch
        FrquencedemandeHzEditFieldLabel  matlab.ui.control.Label
        FrquencedemandeHzEditField      matlab.ui.control.EditField
        NoteDropDownLabel               matlab.ui.control.Label
        NoteDropDown                    matlab.ui.control.DropDown
        CordeTab                        matlab.ui.container.Tab
        MasseliniqueEditFieldLabel      matlab.ui.control.Label
        MasseliniqueEditField           matlab.ui.control.EditField
        LongueurEditFieldLabel          matlab.ui.control.Label
        LongueurEditField               matlab.ui.control.EditField
        FrottementEditFieldLabel        matlab.ui.control.Label
        FrottementEditField             matlab.ui.control.EditField
        TensionmcaniqueEditFieldLabel   matlab.ui.control.Label
        TensionmcaniqueEditField        matlab.ui.control.EditField
        Segmentsx0EditFieldLabel        matlab.ui.control.Label
        Segmentsx0EditField             matlab.ui.control.EditField
        Segmentsv0EditFieldLabel        matlab.ui.control.Label
        Segmentsv0EditField             matlab.ui.control.EditField
        PositionrelativeEditFieldLabel  matlab.ui.control.Label
        ImpulsionPositionrelativeEditField  matlab.ui.control.NumericEditField
        ImpulsionSwitchLabel            matlab.ui.control.Label
        ImpulsionSwitch                 matlab.ui.control.Switch
        ConfigurationTab                matlab.ui.container.Tab
        PositionrelativeducapteurLabel  matlab.ui.control.Label
        PositionrelativeducapteurEditField  matlab.ui.control.EditField
        PositionrelativedelactuateurEditFieldLabel  matlab.ui.control.Label
        PositionrelativedelactuateurEditField  matlab.ui.control.EditField
        PolaritdelartroactionLabel      matlab.ui.control.Label
        PolaritdelartroactionEditField  matlab.ui.control.EditField
        DistancecapteurcordeEditFieldLabel  matlab.ui.control.Label
        DistancecapteurcordeEditField   matlab.ui.control.EditField
        AvancTab                        matlab.ui.container.Tab
        PasdesimulationsLabel           matlab.ui.control.Label
        PasdesimulationsEditField       matlab.ui.control.EditField
        NombredesegmentsLabel           matlab.ui.control.Label
        NombredesegmentsEditField       matlab.ui.control.EditField
        GainVoltageForceEditFieldLabel  matlab.ui.control.Label
        GainVoltageForceEditField       matlab.ui.control.EditField
        FrquencedecoupureLabel          matlab.ui.control.Label
        FrquencedecoupureEditField      matlab.ui.control.EditField
        GainCourantVoltageEditFieldLabel  matlab.ui.control.Label
        GainCourantVoltageEditField     matlab.ui.control.EditField
        CircuitPassehautEditFieldLabel  matlab.ui.control.Label
        CircuitPassehautEditField       matlab.ui.control.EditField
        CircuitPassebasEditFieldLabel   matlab.ui.control.Label
        CircuitPassebasEditField        matlab.ui.control.EditField
        AccordPEditFieldLabel           matlab.ui.control.Label
        AccordPEditField                matlab.ui.control.EditField
        GainAngleTensionEditFieldLabel  matlab.ui.control.Label
        GainAngleTensionEditField       matlab.ui.control.EditField
        FreqMesTransfEditFieldLabel     matlab.ui.control.Label
        FreqMesTransfEditField          matlab.ui.control.EditField
        NotesTab                        matlab.ui.container.Tab
        CommentaireTextAreaLabel        matlab.ui.control.Label
        CommentaireTextArea             matlab.ui.control.TextArea
        NewTab                          matlab.ui.container.Tab
        RfrencefrquenceEditFieldLabel   matlab.ui.control.Label
        RfrencefrquenceEditField        matlab.ui.control.EditField
        ClefVitessemaximaleEditFieldLabel  matlab.ui.control.Label
        ClefVitessemaximaleEditField    matlab.ui.control.EditField
        ClefFrqdecoupureEditFieldLabel  matlab.ui.control.Label
        ClefFrqdecoupureEditField       matlab.ui.control.EditField
        GainVoltageAngleEditFieldLabel  matlab.ui.control.Label
        GainVoltageAngleEditField       matlab.ui.control.EditField
        HystrsisVaEditFieldLabel        matlab.ui.control.Label
        HystrsisVaEditField             matlab.ui.control.EditField
        HystrsisVbEditFieldLabel        matlab.ui.control.Label
        HystrsisVbEditField             matlab.ui.control.EditField
        LimitedetensionEditFieldLabel   matlab.ui.control.Label
        LimitedetensionEditField        matlab.ui.control.EditField
        GainLumireCourantEditFieldLabel  matlab.ui.control.Label
        GainLumireCourantEditField      matlab.ui.control.EditField
        AccordIEditFieldLabel           matlab.ui.control.Label
        AccordIEditField                matlab.ui.control.EditField
        InverserpolarisationCheckBox    matlab.ui.control.CheckBox
        HiddenGroup                     matlab.ui.container.TabGroup
        hiddenTab                       matlab.ui.container.Tab
        AutoentretienSwitchLabel        matlab.ui.control.Label
        AutoentretienSwitch             matlab.ui.control.Switch
        AutoaccordSwitchLabel           matlab.ui.control.Label
        AutoaccordSwitch                matlab.ui.control.Switch
    end

    properties (Access = private)
        SimulationParams SimulationParameterManager;% Description
        Logger ErrorLogger;
        simulatorManager SimulatorManager;
        CordeAnimator AnimationCordeCreator;
        simulationDataFormator SimulationDataFormator;
        
        flagStopAnimation = false;
        flagSimulationEnCours = false;
    end
    
    methods (Access = private)
        
        function results = ParseDoublesStrOrExpr(app, value)
            try
                splittedValue =  strsplit(value,';');
                valuesAsDouble = cellfun( @(x) str2double(x),splittedValue);
                if(size(valuesAsDouble,2)==1)
                    if(isnan(valuesAsDouble))
                        results = eval(value);
                    else
                        results(1) = valuesAsDouble;
                    end
                elseif(any(arrayfun(@(x) isnan(x), valuesAsDouble)))
                    results = eval(value);
                else
                    results = valuesAsDouble;
                end
            catch ME
                app.Logger.Warn(ME.message);
                results = double.empty(0);
            end
        end
        
        function SetStatut(app, statut)
            app.StatutLabel.Text = sprintf('Statut : %s', mlreportgen.utils.toString(statut));
        end
        
        function results = SetParsedValue(app,field)
            results = ParseDoublesStrOrExpr(app, field.Value);
            field.Value = app1.FormatDoubleArrayToString(results);
        end
        
        function UpdateFieldsToSimulationParams(app)
            app.DuredesimulationEditField.Value = app.SimulationParams.Duration;
            app.FrquencedemandeHzEditField.Value = app1.FormatDoubleArrayToString(app.SimulationParams.Frequence_final);
            app.MasseliniqueEditField.Value =  app1.FormatDoubleArrayToString(app.SimulationParams.LinearDensity);
            app.LongueurEditField.Value =  app1.FormatDoubleArrayToString(app.SimulationParams.Length);
            app.PositionrelativeducapteurEditField.Value =  app1.FormatDoubleArrayToString(app.SimulationParams.pos_capteur_relative);
            app.PositionrelativedelactuateurEditField.Value =  app1.FormatDoubleArrayToString(app.SimulationParams.pos_actuateur_relative);
            app.PolaritdelartroactionEditField.Value =  app1.FormatDoubleArrayToString(app.SimulationParams.polarite);
            app.FrottementEditField.Value =app1.FormatDoubleArrayToString(app.SimulationParams.frottement);
            app.TensionmcaniqueEditField.Value = app1.FormatDoubleArrayToString(app.SimulationParams.T_0);
            app.Segmentsx0EditField.Value = app1.FormatDoubleArrayToString(app.SimulationParams.x_0);
            app.Segmentsv0EditField.Value = app1.FormatDoubleArrayToString(app.SimulationParams.v_0);
            app.DistancecapteurcordeEditField.Value = app1.FormatDoubleArrayToString(app.SimulationParams.position_centre);
            app.PasdesimulationsEditField.Value = app1.FormatDoubleArrayToString(app.SimulationParams.dt);
            app.NombredesegmentsEditField.Value = app1.FormatDoubleArrayToString(app.SimulationParams.N);
            
            if app.SimulationParams.isImpulseOn
                app.ImpulsionSwitch.Value = 'On' ;
            else
                app.ImpulsionSwitch.Value = 'Off' ;
            end
            app.ImpulsionPositionrelativeEditField.Enable = app.SimulationParams.isImpulseOn;
            app.ImpulsionPositionrelativeEditField.Value = app.SimulationParams.impulse_relative_position;
            app.GainVoltageForceEditField.Value = app1.FormatDoubleArrayToString(app.SimulationParams.actuateur_force_magnetique);
            app.FrquencedecoupureEditField.Value = app1.FormatDoubleArrayToString(app.SimulationParams.actuateur_circuitRL_s);
            app.GainCourantVoltageEditField.Value = app1.FormatDoubleArrayToString(app.SimulationParams.capteur_R3);
            app.CircuitPassehautEditField.Value = app1.FormatDoubleArrayToString(app.SimulationParams.circuit_passe_haut);
            app.CircuitPassebasEditField.Value = app1.FormatDoubleArrayToString(app.SimulationParams.circuit_passe_bas);
            app.AccordPEditField.Value = app1.FormatDoubleArrayToString(app.SimulationParams.regulateur_gain);
            app.GainAngleTensionEditField.Value = app1.FormatDoubleArrayToString(app.SimulationParams.servoclef_clef);
            app.FreqMesTransfEditField.Value = app1.FormatDoubleArrayToString(app.SimulationParams.freq_mes_transfer);
        end
    end
    
    methods (Static)
        function resultStr = FormatDoubleArrayToString(values)
            resultStr = strjoin(arrayfun(@(value) sprintf('%.3g',value), values, 'UniformOutput', false),';');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Logger = ErrorLogger(app.StatutLabel, app.UIFigure);
            app.SimulationParams = SimulationParameterManager();
            simulator = SystemSimulator();
            app.simulatorManager = SimulatorManager(simulator);
            app.CordeAnimator = AnimationCordeCreator();
            app.simulationDataFormator = SimulationDataFormator(@(rowsDatas) set(app.ResultUITable, 'data', rowsDatas));
            
            app.simulatorManager.add_listener_formator(app.simulationDataFormator);
            set(app.ResultUITable, 'data', app.simulationDataFormator.rowsDatas);

            app.Reset(); %Set everything to default.
            
            %Stupid app designer doesn't let me change axes creation.
            %Apparently this is a bug.
            title(app.UIAxesPositionSegment, 'Position d''un segment')
            xlabel(app.UIAxesPositionSegment, 'Temps')
            ylabel(app.UIAxesPositionSegment, 'Position')
            
        end

        % Button pushed function: SimulerButton
        function Simulate(app, event)
            %Set status
            if(app.flagSimulationEnCours)
                return;
            end
            app.flagSimulationEnCours = true;
            app.SetStatut("Simulation en cours.");
            app.SimulerButton.Enable = false;
            app.ChargerButton.Enable = false;
            app.RinitialiserButton.Text = 'Arrêter';
            %Start simulation
            app.simulatorManager.SetAutoEntretien(true);
            app.simulatorManager.SetAutoAccord(true);
            app.simulationDataFormator.Reset();
            app.simulationDataFormator.SetSimulation(app.SimulationParams);
            app.ResultUITable.ColumnName = app.simulationDataFormator.ColumnName;
            set(app.ResultUITable, 'data', {});
            
            app.simulatorManager.Simulate(app.SimulationParams.EnumerateSimulationParameters(), @(s) app.SetStatut(s));
            
            app.EnregistrerButton.Enable = true;
            app.ChargerButton.Enable = true;
            app.SimulerButton.Enable = true;
            
            %Show corresponding tab + set position segment
            NnSim = app.SimulationParams.GetNumberOfSimulation();
            app.SimulationDropDown.Items =arrayfun(@(x) num2str(x), 1:NnSim, 'UniformOutput',false);
            app.SimulationDropDown.Value = '1';
            app.SimulationDropDown_Animation.Items = app.SimulationDropDown.Items;
            app.SimulationDropDown_Animation.Value = '1';
            
            app.SegmentDropDown.Items = arrayfun(@(x) num2str(x), 1:(app.simulatorManager.resultsByParameter{1}{1}.corde.N), 'UniformOutput',false);
            
            %      app.simulationDataFormator.SetResults(app.simulatorManager.resultsByParameter);
            %             %Done at each iteration now.
            %           %  set(app.ResultUITable, 'data', app.simulationDataFormator.rowsDatas);
            
            app.SetStatut(sprintf('Simulations terminées (%i)', NnSim));
            app.flagSimulationEnCours = false;
            app.RinitialiserButton.Text = 'Réinitialiser';
        end

        % Button pushed function: RinitialiserButton
        function Reset(app, event)
            %Set component status
            if(app.RinitialiserButton.Text == "Arrêter")
                app.simulatorManager.Stop();
                app.RinitialiserButton.Text = 'Réinitialiser';
            else
                app.SimulerButton.Enable = true;
                app.ChargerButton.Enable = true;
                app.EnregistrerButton.Enable = false;
                app.SimulationParams.SetToDefault();
                UpdateFieldsToSimulationParams(app);
                
                %Data table tab
                app.ResultUITable.ColumnName = SimulationDataFormator.GetDefaultColumnName;
                set(app.ResultUITable, 'data', {});
                
                app.SetStatut("Prêt.");
            end


        end

        % Value changed function: FrquencedemandeHzEditField
        function EditFrequence(app, event)
            app.SimulationParams.Frequence_final = SetParsedValue(app, app.FrquencedemandeHzEditField);
        end

        % Value changed function: MasseliniqueEditField
        function EditMassLineique(app, event)
            app.SimulationParams.LinearDensity = SetParsedValue(app, app.MasseliniqueEditField);
        end

        % Value changed function: LongueurEditField
        function EditLongueur(app, event)
            app.SimulationParams.Length = SetParsedValue(app, app.LongueurEditField);
        end

        % Value changed function: DuredesimulationEditField
        function EditDuree(app, event)
            app.SimulationParams.Duration = app.DuredesimulationEditField.Value;
        end

        % Value changed function: PositionrelativeducapteurEditField
        function EditPositionCapteur(app, event)
            app.SimulationParams.pos_capteur_relative = SetParsedValue(app, app.PositionrelativeducapteurEditField);
        end

        % Value changed function: 
        % PositionrelativedelactuateurEditField
        function EditPositionActuateur(app, event)
            app.SimulationParams.pos_actuateur_relative = SetParsedValue(app, app.PositionrelativedelactuateurEditField);
        end

        % Value changed function: PolaritdelartroactionEditField
        function PolaritdelartroactionEditFieldValueChanged(app, event)
            app.SimulationParams.polarite = SetParsedValue(app, app.PolaritdelartroactionEditField);
        end

        % Value changed function: FrottementEditField
        function FrottementEditFieldValueChanged(app, event)
            app.SimulationParams.frottement = SetParsedValue(app, app.FrottementEditField);
        end

        % Value changed function: TensionmcaniqueEditField
        function TensionmcaniqueEditFieldValueChanged(app, event)
            app.SimulationParams.T_0 = SetParsedValue(app, app.TensionmcaniqueEditField);
        end

        % Value changed function: Segmentsx0EditField
        function Segmentsx0EditFieldValueChanged(app, event)
            app.SimulationParams.x_0 = SetParsedValue(app, app.Segmentsx0EditField);
        end

        % Value changed function: Segmentsv0EditField
        function Segmentsv0EditFieldValueChanged(app, event)
            app.SimulationParams.v_0 = SetParsedValue(app, app.Segmentsv0EditField);
        end

        % Value changed function: DistancecapteurcordeEditField
        function DistancecapteurcordeEditFieldValueChanged(app, event)
            app.SimulationParams.position_centre = SetParsedValue(app, app.DistancecapteurcordeEditField);
        end

        % Value changed function: PasdesimulationsEditField
        function PasdesimulationsEditFieldValueChanged(app, event)
            app.SimulationParams.dt = SetParsedValue(app, app.PasdesimulationsEditField);
        end

        % Value changed function: NombredesegmentsEditField
        function NombredesegmentsEditFieldValueChanged(app, event)
            app.SimulationParams.N = SetParsedValue(app, app.NombredesegmentsEditField);
        end

        % Button pushed function: EnregistrerButton
        function EnregistrerButtonPushed(app, event)
            %Create json
            [file,path,~] = uiputfile(['datas ' datestr(now, 'yyyymmddhhMMss') '.mat'], "Choisissez le nom du fichier pour enregistrer les données.");
            if ~isequal(file,0)
                params = app.SimulationParams;
                note = app.CommentaireTextArea.Value;
                save([path file], 'params', 'note');
            end
        end

        % Button pushed function: ChargerButton
        function ChargerButtonPushed(app, event)
            [file,path] = uigetfile('*.mat', "Sélectionez la simulation à charger.");
            if ~isequal(file,0)
                load([path file], 'params', 'note');
                app.SimulationParams = params;
                app.CommentaireTextArea.Value = note;
                UpdateFieldsToSimulationParams(app);
                app.SetStatut("Prêt.");
                app.EnregistrerButton.Enable = true;
                app.ChargerButton.Enable = true;
                app.SimulerButton.Enable = true;
            end
        end

        % Value changed function: ImpulsionSwitch
        function ImpulsionSwitchValueChanged(app, event)
            value = app.ImpulsionSwitch.Value;
            if(value == "On")
                app.ImpulsionPositionrelativeEditField.Enable = true;
                app.SimulationParams.isImpulseOn = true;
            else
                app.ImpulsionPositionrelativeEditField.Enable = false;
                app.SimulationParams.isImpulseOn = false;
            end
        end

        % Value changed function: ImpulsionPositionrelativeEditField
        function ImpulsionPositionrelativeEditFieldValueChanged(app, event)
            app.SimulationParams.impulse_relative_position = SetParsedValue(app, app.ImpulsionPositionrelativeEditField);
        end

        % Value changed function: GainVoltageForceEditField
        function GainVoltageForceEditFieldValueChanged(app, event)
            app.SimulationParams.actuateur_force_magnetique = SetParsedValue(app, app.GainVoltageForceEditField);
        end

        % Value changed function: FrquencedecoupureEditField
        function FrquencedecoupureEditFieldValueChanged(app, event)
            app.SimulationParams.actuateur_circuitRL_s = SetParsedValue(app, app.FrquencedecoupureEditField);
        end

        % Value changed function: GainCourantVoltageEditField
        function GainCourantVoltageEditFieldValueChanged(app, event)
            app.SimulationParams.capteur_R3 = SetParsedValue(app, app.GainCourantVoltageEditField);
        end

        % Value changed function: CircuitPassehautEditField
        function CircuitPassehautEditFieldValueChanged(app, event)
            app.SimulationParams.circuit_passe_haut = SetParsedValue(app, app.CircuitPassehautEditField);
        end

        % Value changed function: CircuitPassebasEditField
        function CircuitPassebasEditFieldValueChanged(app, event)
            app.SimulationParams.circuit_passe_bas = SetParsedValue(app, app.CircuitPassebasEditField);
        end

        % Value changed function: AccordPEditField
        function AccordPEditFieldValueChanged(app, event)
            app.SimulationParams.regulateur_gain = SetParsedValue(app, app.AccordPEditField);
        end

        % Value changed function: GainAngleTensionEditField
        function GainAngleTensionEditFieldValueChanged(app, event)
            app.SimulationParams.servoclef_clef = SetParsedValue(app, app.GainAngleTensionEditField);
        end

        % Value changed function: FreqMesTransfEditField
        function FreqMesTransfEditFieldValueChanged(app, event)
            app.SimulationParams.freq_mes_transfer = SetParsedValue(app, app.FreqMesTransfEditField);
        end

        % Button pushed function: TensionButton
        function TensionButtonPushed(app, event)
            open_system('SystemeCompletProcedural/Tension_corde');
        end

        % Button pushed function: PositioncordeButton
        function PositioncordeButtonPushed(app, event)
            open_system('SystemeCompletProcedural/Position_corde');
        end

        % Button pushed function: PositioncapteButton
        function PositioncapteButtonPushed(app, event)
            open_system('SystemeCompletProcedural/Position_captée');
        end

        % Button pushed function: Sortie05VButton
        function Sortie05VButtonPushed(app, event)
            open_system('SystemeCompletProcedural/Sortie_0-5V');
        end

        % Button pushed function: FrquenceButton
        function FrquenceButtonPushed(app, event)
            open_system('SystemeCompletProcedural/Freq');
        end

        % Button pushed function: ActuateurButton
        function ActuateurButtonPushed(app, event)
            open_system('SystemeCompletProcedural/sortie_actuateur');
        end

        % Button pushed function: RgulateurButton
        function RgulateurButtonPushed(app, event)
            open_system('SystemeCompletProcedural/Régulateur scope');
        end

        % Button pushed function: JouerButton
        function JouerButtonPushed(app, event)
            %             UIAxesAnimation;
            %Get first sim.
            try
                idxSim = str2double( app.SimulationDropDown_Animation.Value);
                param = app.simulatorManager.resultsByParameter{idxSim}{1};
                data = app.simulatorManager.resultsByParameter{idxSim}{2}.corde_mesure;
                slowFactor = app.RalentissementEditField.Value;
                
                app.UIAxesAnimation.XLim = [0, param.corde.N];
                %                 ymax = 1.25*max(max(max(abs(data.Data)))); %0.003
                %                 app.UIAxesAnimation.YLim = [-1*ymax, ymax];
                
                app.CordeAnimator.AnimateCorde(app.UIAxesAnimation, data, param.corde.N, param.dt, slowFactor, @() app.flagStopAnimation)
                
                app.flagStopAnimation = false;
            catch ME
                app.SetStatut(strcat("Erreur : Animation de la corde -  ", ME.message));
            end
            
        end

        % Button pushed function: InterrompreButton
        function InterrompreButtonPushed(app, event)
            app.flagStopAnimation = true;
        end

        % Value changed function: SimulationDropDown
        function SimulationDropDownValueChanged(app, event)
            idxSim = str2double(app.SimulationDropDown.Value);
            app.SegmentDropDown.Items = arrayfun(@(x) num2str(x), 1:(app.simulatorManager.resultsByParameter{idxSim}{1}.corde.N), 'UniformOutput',false);
        end

        % Value changed function: SegmentDropDown
        function SegmentDropDownValueChanged(app, event)
            try
                segment = str2double(app.SegmentDropDown.Value);
                idxSim = str2double(app.SimulationDropDown.Value);
                t = app.simulatorManager.resultsByParameter{idxSim}{2}.corde_mesure.Time;
                pos = app.simulatorManager.resultsByParameter{idxSim}{2}.corde_mesure.Data(:,segment);
                fig = app.UIAxesPositionSegment;
                fig.XLim = [app.simulatorManager.resultsByParameter{idxSim}{2}.SimulationMetadata.ModelInfo.StartTime app.simulatorManager.resultsByParameter{idxSim}{2}.SimulationMetadata.ModelInfo.StopTime];
                plot(fig, t, pos,'-');
            catch ME
                app.SetStatut(strcat('Erreur (Affichage de la position) : ', ME.message)) ;
            end
            
        end

        % Value changed function: NoteDropDown
        function NoteDropDownValueChanged(app, event)
            value = app.NoteDropDown.Value;
            switch value
                case 'Si'
                    noteFrq = 123.470825314031;
                case 'Sib (La#)'
                    noteFrq = 116.540940379522;
                case 'La'
                    noteFrq = 110;
                case 'Sol#'
                    noteFrq = 103.826174394986;
                case 'Sol'
                    noteFrq = 97.9988589954373;
                case 'Fa#'
                    noteFrq = 92.4986056779086;
                case 'Fa'
                    noteFrq = 87.307057858251;
                case 'Mi'
                    noteFrq = 82.4068892282175;
                case 'Mib (Ré#)'
                    noteFrq = 77.7817459305202;
                case 'Ré'
                    noteFrq = 73.4161919793519;
                case 'Do#'
                    noteFrq = 69.295657744218;
                case 'Do'
                    noteFrq = 65.4063913251496;
            end
            if(app.NoteOptionSwitch.Value == "Choisir")
                app.SimulationParams.Frequence_final = noteFrq;
            else
                app.SimulationParams.Frequence_final = [app.SimulationParams.Frequence_final noteFrq];
            end
            app.UpdateFieldsToSimulationParams();
        end

        % Value changed function: AutoentretienSwitch
        function AutoentretienSwitchValueChanged(app, event)
            value = app.AutoentretienSwitch.Value;
            if(isempty(value))
                app.simulatorManager.SetAutoEntretien(false);
            else
                app.simulatorManager.SetAutoEntretien(true);
            end
        end

        % Value changed function: AutoaccordSwitch
        function AutoaccordSwitchValueChanged(app, event)
            value = app.AutoaccordSwitch.Value;
            if(isempty(value))
                app.simulatorManager.SetAutoAccord(false);
            else
                app.simulatorManager.SetAutoAccord(true);
            end
        end

        % Value changed function: RfrencefrquenceEditField
        function RfrencefrquenceEditFieldValueChanged(app, event)
            app.SimulationParams.clockFreq = SetParsedValue(app, app.RfrencefrquenceEditField);
        end

        % Value changed function: ClefVitessemaximaleEditField
        function ClefVitessemaximaleEditFieldValueChanged(app, event)
            app.SimulationParams.servoClefVitesseMax = SetParsedValue(app, app.ClefVitessemaximaleEditField);
        end

        % Value changed function: ClefFrqdecoupureEditField
        function ClefFrqdecoupureEditFieldValueChanged(app, event)
            app.SimulationParams.freq_coupure = SetParsedValue(app, app.ClefFrqdecoupureEditField);
        end

        % Value changed function: GainVoltageAngleEditField
        function GainVoltageAngleEditFieldValueChanged(app, event)
            app.SimulationParams.gainVoltageAngle = SetParsedValue(app, app.GainVoltageAngleEditField);
        end

        % Value changed function: HystrsisVaEditField
        function HystrsisVaEditFieldValueChanged(app, event)
            app.SimulationParams.Circuit_Va = SetParsedValue(app, app.HystrsisVaEditField);
        end

        % Value changed function: HystrsisVbEditField
        function HystrsisVbEditFieldValueChanged(app, event)
            app.SimulationParams.Circuit_Vb = SetParsedValue(app, app.HystrsisVbEditField);
        end

        % Value changed function: LimitedetensionEditField
        function LimitedetensionEditFieldValueChanged(app, event)
            app.SimulationParams.tensionAjustable = SetParsedValue(app, app.LimitedetensionEditField);
        end

        % Value changed function: GainLumireCourantEditField
        function GainLumireCourantEditFieldValueChanged(app, event)
            app.SimulationParams.capteur_courantMax = SetParsedValue(app, app.GainLumireCourantEditField);
        end

        % Value changed function: AccordIEditField
        function AccordIEditFieldValueChanged(app, event)
            app.SimulationParams.regulateur_accordI = SetParsedValue(app, app.AccordIEditField);
        end

        % Value changed function: InverserpolarisationCheckBox
        function InverserpolarisationCheckBoxValueChanged(app, event)
            value = app.InverserpolarisationCheckBox.Value;
            app.SimulationParams.IsPolarisationInverted = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ResultGroup = uitabgroup(app.UIFigure);
            app.RsultatsTab = uitab(app.ResultGroup);
            app.RsultatsTab.Title = 'Résultats';
            app.ResultUITable = uitable(app.RsultatsTab);
            app.ResultUITable.ColumnName = {'Id'; 'Succès'; 'Temps'; 'Justesse'; 'Vitesse d''accord'};
            app.ResultUITable.RowName = {''};
            app.ScopeTab = uitab(app.ResultGroup);
            app.ScopeTab.Title = 'Scope';
            app.TensionButton = uibutton(app.ScopeTab, 'push');
            app.TensionButton.ButtonPushedFcn = createCallbackFcn(app, @TensionButtonPushed, true);
            app.TensionButton.Text = 'Tension';
            app.PositioncordeButton = uibutton(app.ScopeTab, 'push');
            app.PositioncordeButton.ButtonPushedFcn = createCallbackFcn(app, @PositioncordeButtonPushed, true);
            app.PositioncordeButton.Text = 'Position corde';
            app.PositioncapteButton = uibutton(app.ScopeTab, 'push');
            app.PositioncapteButton.ButtonPushedFcn = createCallbackFcn(app, @PositioncapteButtonPushed, true);
            app.PositioncapteButton.Text = 'Position captée';
            app.Sortie05VButton = uibutton(app.ScopeTab, 'push');
            app.Sortie05VButton.ButtonPushedFcn = createCallbackFcn(app, @Sortie05VButtonPushed, true);
            app.Sortie05VButton.Text = 'Sortie 0-5V';
            app.FrquenceButton = uibutton(app.ScopeTab, 'push');
            app.FrquenceButton.ButtonPushedFcn = createCallbackFcn(app, @FrquenceButtonPushed, true);
            app.FrquenceButton.Text = 'Fréquence';
            app.ActuateurButton = uibutton(app.ScopeTab, 'push');
            app.ActuateurButton.ButtonPushedFcn = createCallbackFcn(app, @ActuateurButtonPushed, true);
            app.ActuateurButton.Text = 'Actuateur';
            app.RgulateurButton = uibutton(app.ScopeTab, 'push');
            app.RgulateurButton.ButtonPushedFcn = createCallbackFcn(app, @RgulateurButtonPushed, true);
            app.RgulateurButton.Text = 'Régulateur';
            app.AnimationdelacordeTab = uitab(app.ResultGroup);
            app.AnimationdelacordeTab.Title = 'Animation de la corde';
            app.UIAxesAnimation = uiaxes(app.AnimationdelacordeTab);
            title(app.UIAxesAnimation, 'Animation de la corde')
            xlabel(app.UIAxesAnimation, 'Segment')
            ylabel(app.UIAxesAnimation, 'Position')
            app.UIAxesAnimation.XLim = [0 1];
            app.UIAxesAnimation.YLim = [-0.003 0.003];
            app.JouerButton = uibutton(app.AnimationdelacordeTab, 'push');
            app.JouerButton.ButtonPushedFcn = createCallbackFcn(app, @JouerButtonPushed, true);
            app.JouerButton.Text = 'Jouer';
            app.InterrompreButton = uibutton(app.AnimationdelacordeTab, 'push');
            app.InterrompreButton.ButtonPushedFcn = createCallbackFcn(app, @InterrompreButtonPushed, true);
            app.InterrompreButton.Text = 'Interrompre';
            app.RalentissementEditFieldLabel = uilabel(app.AnimationdelacordeTab);
            app.RalentissementEditFieldLabel.Text = 'Ralentissement';
            app.RalentissementEditField = uieditfield(app.AnimationdelacordeTab, 'text');
            app.RalentissementEditField.Value = '100';
            app.SimulationDropDown_2Label = uilabel(app.AnimationdelacordeTab);
            app.SimulationDropDown_2Label.Text = 'Simulation #';
            app.SimulationDropDown_Animation = uidropdown(app.AnimationdelacordeTab);
            app.SimulationDropDown_Animation.Items = {'1', '2', '3', '4'};
            app.SimulationDropDown_Animation.Value = '1';
            app.PositionsegmentTab = uitab(app.ResultGroup);
            app.PositionsegmentTab.Title = 'Position segment';
            app.SegmentDropDownLabel = uilabel(app.PositionsegmentTab);
            app.SegmentDropDownLabel.Text = 'Segment #';
            app.SegmentDropDown = uidropdown(app.PositionsegmentTab);
            app.SegmentDropDown.Items = {'1', '2', '3'};
            app.SegmentDropDown.ValueChangedFcn = createCallbackFcn(app, @SegmentDropDownValueChanged, true);
            app.SegmentDropDown.Value = '1';
            app.SimulationDropDownLabel = uilabel(app.PositionsegmentTab);
            app.SimulationDropDownLabel.Text = 'Simulation #';
            app.SimulationDropDown = uidropdown(app.PositionsegmentTab);
            app.SimulationDropDown.Items = {'1', '2', '3', '4'};
            app.SimulationDropDown.ValueChangedFcn = createCallbackFcn(app, @SimulationDropDownValueChanged, true);
            app.SimulationDropDown.Value = '1';
            app.UIAxesPositionSegment = uiaxes(app.PositionsegmentTab);
            title(app.UIAxesPositionSegment, 'Animation de la corde')
            xlabel(app.UIAxesPositionSegment, 'Segment')
            ylabel(app.UIAxesPositionSegment, 'Position')
            app.UIAxesPositionSegment.XLim = [0 1];
            app.UIAxesPositionSegment.YLim = [-0.003 0.003];
            app.LesAccords2Label = uilabel(app.UIFigure);
            app.LesAccords2Label.Text = 'Les Accords2';
            app.SimulerButton = uibutton(app.UIFigure, 'push');
            app.SimulerButton.ButtonPushedFcn = createCallbackFcn(app, @Simulate, true);
            app.SimulerButton.Text = 'Simuler';
            app.EnregistrerButton = uibutton(app.UIFigure, 'push');
            app.EnregistrerButton.ButtonPushedFcn = createCallbackFcn(app, @EnregistrerButtonPushed, true);
            app.EnregistrerButton.Enable = 'off';
            app.EnregistrerButton.Text = 'Enregistrer';
            app.ChargerButton = uibutton(app.UIFigure, 'push');
            app.ChargerButton.ButtonPushedFcn = createCallbackFcn(app, @ChargerButtonPushed, true);
            app.ChargerButton.Enable = 'off';
            app.ChargerButton.Text = 'Charger';
            app.RinitialiserButton = uibutton(app.UIFigure, 'push');
            app.RinitialiserButton.ButtonPushedFcn = createCallbackFcn(app, @Reset, true);
            app.RinitialiserButton.Text = 'Réinitialiser';
            app.StatutLabel = uilabel(app.UIFigure);
            app.StatutLabel.Text = 'Statut';
            app.ParameterGroup = uitabgroup(app.UIFigure);
            app.SimulationTab = uitab(app.ParameterGroup);
            app.SimulationTab.Title = 'Simulation';
            app.DuredesimulationEditFieldLabel = uilabel(app.SimulationTab);
            app.DuredesimulationEditFieldLabel.Text = {'Durée de '; 'simulation'};
            app.DuredesimulationEditField = uieditfield(app.SimulationTab, 'numeric');
            app.DuredesimulationEditField.ValueChangedFcn = createCallbackFcn(app, @EditDuree, true);
            app.NoteOptionSwitch = uiswitch(app.SimulationTab, 'toggle');
            app.NoteOptionSwitch.Items = {'Choisir', 'Ajouter'};
            app.NoteOptionSwitch.Value = 'Choisir';
            app.FrquencedemandeHzEditFieldLabel = uilabel(app.SimulationTab);
            app.FrquencedemandeHzEditFieldLabel.Text = {'Fréquence '; 'demandée (Hz)'};
            app.FrquencedemandeHzEditField = uieditfield(app.SimulationTab, 'text');
            app.FrquencedemandeHzEditField.ValueChangedFcn = createCallbackFcn(app, @EditFrequence, true);
            app.NoteDropDownLabel = uilabel(app.SimulationTab);
            app.NoteDropDownLabel.Text = 'Note';
            app.NoteDropDown = uidropdown(app.SimulationTab);
            app.NoteDropDown.Items = {'Si', 'Sib (La#)', 'La', 'Sol#', 'Sol', 'Fa#', 'Fa', 'Mi', 'Mib (Ré#)', 'Ré', 'Do#', 'Do'};
            app.NoteDropDown.ValueChangedFcn = createCallbackFcn(app, @NoteDropDownValueChanged, true);
            app.NoteDropDown.Value = 'Do';
            app.CordeTab = uitab(app.ParameterGroup);
            app.CordeTab.Title = 'Corde';
            app.MasseliniqueEditFieldLabel = uilabel(app.CordeTab);
            app.MasseliniqueEditFieldLabel.Text = 'Masse linéique';
            app.MasseliniqueEditField = uieditfield(app.CordeTab, 'text');
            app.MasseliniqueEditField.ValueChangedFcn = createCallbackFcn(app, @EditMassLineique, true);
            app.LongueurEditFieldLabel = uilabel(app.CordeTab);
            app.LongueurEditFieldLabel.Text = 'Longueur';
            app.LongueurEditField = uieditfield(app.CordeTab, 'text');
            app.LongueurEditField.ValueChangedFcn = createCallbackFcn(app, @EditLongueur, true);
            app.FrottementEditFieldLabel = uilabel(app.CordeTab);
            app.FrottementEditFieldLabel.Text = 'Frottement';
            app.FrottementEditField = uieditfield(app.CordeTab, 'text');
            app.FrottementEditField.ValueChangedFcn = createCallbackFcn(app, @FrottementEditFieldValueChanged, true);
            app.TensionmcaniqueEditFieldLabel = uilabel(app.CordeTab);
            app.TensionmcaniqueEditFieldLabel.Text = 'Tension mécanique';
            app.TensionmcaniqueEditField = uieditfield(app.CordeTab, 'text');
            app.TensionmcaniqueEditField.ValueChangedFcn = createCallbackFcn(app, @TensionmcaniqueEditFieldValueChanged, true);
            app.Segmentsx0EditFieldLabel = uilabel(app.CordeTab);
            app.Segmentsx0EditFieldLabel.Text = 'Segments x0';
            app.Segmentsx0EditField = uieditfield(app.CordeTab, 'text');
            app.Segmentsx0EditField.ValueChangedFcn = createCallbackFcn(app, @Segmentsx0EditFieldValueChanged, true);
            app.Segmentsv0EditFieldLabel = uilabel(app.CordeTab);
            app.Segmentsv0EditFieldLabel.Text = 'Segments v0';
            app.Segmentsv0EditField = uieditfield(app.CordeTab, 'text');
            app.Segmentsv0EditField.ValueChangedFcn = createCallbackFcn(app, @Segmentsv0EditFieldValueChanged, true);
            app.Segmentsv0EditField.Tooltip = {'jajaja'};
            app.PositionrelativeEditFieldLabel = uilabel(app.CordeTab);
            app.PositionrelativeEditFieldLabel.Text = 'Position relative';
            app.ImpulsionPositionrelativeEditField = uieditfield(app.CordeTab, 'numeric');
            app.ImpulsionPositionrelativeEditField.ValueChangedFcn = createCallbackFcn(app, @ImpulsionPositionrelativeEditFieldValueChanged, true);
            app.ImpulsionSwitchLabel = uilabel(app.CordeTab);
            app.ImpulsionSwitchLabel.Text = 'Impulsion';
            app.ImpulsionSwitch = uiswitch(app.CordeTab, 'slider');
            app.ImpulsionSwitch.ValueChangedFcn = createCallbackFcn(app, @ImpulsionSwitchValueChanged, true);
            app.ConfigurationTab = uitab(app.ParameterGroup);
            app.ConfigurationTab.Title = 'Configuration';
            app.PositionrelativeducapteurLabel = uilabel(app.ConfigurationTab);
            app.PositionrelativeducapteurLabel.Text = {'Position relative'; ' du capteur'};
            app.PositionrelativeducapteurEditField = uieditfield(app.ConfigurationTab, 'text');
            app.PositionrelativeducapteurEditField.ValueChangedFcn = createCallbackFcn(app, @EditPositionCapteur, true);
            app.PositionrelativedelactuateurEditFieldLabel = uilabel(app.ConfigurationTab);
            app.PositionrelativedelactuateurEditFieldLabel.Text = {'Position relative'; ' de l''actuateur'};
            app.PositionrelativedelactuateurEditField = uieditfield(app.ConfigurationTab, 'text');
            app.PositionrelativedelactuateurEditField.ValueChangedFcn = createCallbackFcn(app, @EditPositionActuateur, true);
            app.PolaritdelartroactionLabel = uilabel(app.ConfigurationTab);
            app.PolaritdelartroactionLabel.Enable = 'off';
            app.PolaritdelartroactionLabel.Text = {'Polarité de '; 'la rétroaction'};
            app.PolaritdelartroactionEditField = uieditfield(app.ConfigurationTab, 'text');
            app.PolaritdelartroactionEditField.ValueChangedFcn = createCallbackFcn(app, @PolaritdelartroactionEditFieldValueChanged, true);
            app.PolaritdelartroactionEditField.Enable = 'off';
            app.DistancecapteurcordeEditFieldLabel = uilabel(app.ConfigurationTab);
            app.DistancecapteurcordeEditFieldLabel.Text = {'Distance '; 'capteur-corde'};
            app.DistancecapteurcordeEditField = uieditfield(app.ConfigurationTab, 'text');
            app.DistancecapteurcordeEditField.ValueChangedFcn = createCallbackFcn(app, @DistancecapteurcordeEditFieldValueChanged, true);
            app.AvancTab = uitab(app.ParameterGroup);
            app.AvancTab.Title = 'Avancé';
            app.PasdesimulationsLabel = uilabel(app.AvancTab);
            app.PasdesimulationsLabel.Text = {'Pas de '; 'simulation [s]'};
            app.PasdesimulationsEditField = uieditfield(app.AvancTab, 'text');
            app.PasdesimulationsEditField.ValueChangedFcn = createCallbackFcn(app, @PasdesimulationsEditFieldValueChanged, true);
            app.NombredesegmentsLabel = uilabel(app.AvancTab);
            app.NombredesegmentsLabel.Text = {'Nombre '; 'de segments'};
            app.NombredesegmentsEditField = uieditfield(app.AvancTab, 'text');
            app.NombredesegmentsEditField.ValueChangedFcn = createCallbackFcn(app, @NombredesegmentsEditFieldValueChanged, true);
            app.GainVoltageForceEditFieldLabel = uilabel(app.AvancTab);
            app.GainVoltageForceEditFieldLabel.Text = 'Gain Voltage/Force';
            app.GainVoltageForceEditField = uieditfield(app.AvancTab, 'text');
            app.GainVoltageForceEditField.ValueChangedFcn = createCallbackFcn(app, @GainVoltageForceEditFieldValueChanged, true);
            app.FrquencedecoupureLabel = uilabel(app.AvancTab);
            app.FrquencedecoupureLabel.Text = {'Fréquence '; 'de coupure'};
            app.FrquencedecoupureEditField = uieditfield(app.AvancTab, 'text');
            app.FrquencedecoupureEditField.ValueChangedFcn = createCallbackFcn(app, @FrquencedecoupureEditFieldValueChanged, true);
            app.GainCourantVoltageEditFieldLabel = uilabel(app.AvancTab);
            app.GainCourantVoltageEditFieldLabel.Text = {'Gain '; 'Courant/Voltage'};
            app.GainCourantVoltageEditField = uieditfield(app.AvancTab, 'text');
            app.GainCourantVoltageEditField.ValueChangedFcn = createCallbackFcn(app, @GainCourantVoltageEditFieldValueChanged, true);
            app.CircuitPassehautEditFieldLabel = uilabel(app.AvancTab);
            app.CircuitPassehautEditFieldLabel.Enable = 'off';
            app.CircuitPassehautEditFieldLabel.Text = 'Circuit Passe-haut';
            app.CircuitPassehautEditField = uieditfield(app.AvancTab, 'text');
            app.CircuitPassehautEditField.ValueChangedFcn = createCallbackFcn(app, @CircuitPassehautEditFieldValueChanged, true);
            app.CircuitPassehautEditField.Enable = 'off';
            app.CircuitPassebasEditFieldLabel = uilabel(app.AvancTab);
            app.CircuitPassebasEditFieldLabel.Enable = 'off';
            app.CircuitPassebasEditFieldLabel.Text = 'Circuit Passe-bas';
            app.CircuitPassebasEditField = uieditfield(app.AvancTab, 'text');
            app.CircuitPassebasEditField.ValueChangedFcn = createCallbackFcn(app, @CircuitPassebasEditFieldValueChanged, true);
            app.CircuitPassebasEditField.Enable = 'off';
            app.AccordPEditFieldLabel = uilabel(app.AvancTab);
            app.AccordPEditFieldLabel.Text = 'Accord P';
            app.AccordPEditField = uieditfield(app.AvancTab, 'text');
            app.AccordPEditField.ValueChangedFcn = createCallbackFcn(app, @AccordPEditFieldValueChanged, true);
            app.GainAngleTensionEditFieldLabel = uilabel(app.AvancTab);
            app.GainAngleTensionEditFieldLabel.Text = 'Gain Angle/Tension';
            app.GainAngleTensionEditField = uieditfield(app.AvancTab, 'text');
            app.GainAngleTensionEditField.ValueChangedFcn = createCallbackFcn(app, @GainAngleTensionEditFieldValueChanged, true);
            app.FreqMesTransfEditFieldLabel = uilabel(app.AvancTab);
            app.FreqMesTransfEditFieldLabel.Enable = 'off';
            app.FreqMesTransfEditFieldLabel.Text = 'Freq. Mes. Transf';
            app.FreqMesTransfEditField = uieditfield(app.AvancTab, 'text');
            app.FreqMesTransfEditField.ValueChangedFcn = createCallbackFcn(app, @FreqMesTransfEditFieldValueChanged, true);
            app.FreqMesTransfEditField.Enable = 'off';
            app.NotesTab = uitab(app.ParameterGroup);
            app.NotesTab.Title = 'Notes';
            app.CommentaireTextAreaLabel = uilabel(app.NotesTab);
            app.CommentaireTextAreaLabel.Text = 'Commentaire';
            app.CommentaireTextArea = uitextarea(app.NotesTab);
            app.NewTab = uitab(app.ParameterGroup);
            app.NewTab.Title = 'New';
            app.RfrencefrquenceEditFieldLabel = uilabel(app.NewTab);
            app.RfrencefrquenceEditFieldLabel.Text = 'Référence fréquence';
            app.RfrencefrquenceEditField = uieditfield(app.NewTab, 'text');
            app.RfrencefrquenceEditField.ValueChangedFcn = createCallbackFcn(app, @RfrencefrquenceEditFieldValueChanged, true);
            app.ClefVitessemaximaleEditFieldLabel = uilabel(app.NewTab);
            app.ClefVitessemaximaleEditFieldLabel.Text = 'Clef - Vitesse maximale';
            app.ClefVitessemaximaleEditField = uieditfield(app.NewTab, 'text');
            app.ClefVitessemaximaleEditField.ValueChangedFcn = createCallbackFcn(app, @ClefVitessemaximaleEditFieldValueChanged, true);
            app.ClefFrqdecoupureEditFieldLabel = uilabel(app.NewTab);
            app.ClefFrqdecoupureEditFieldLabel.Text = 'Clef - Fréq. de coupure';
            app.ClefFrqdecoupureEditField = uieditfield(app.NewTab, 'text');
            app.ClefFrqdecoupureEditField.ValueChangedFcn = createCallbackFcn(app, @ClefFrqdecoupureEditFieldValueChanged, true);
            app.GainVoltageAngleEditFieldLabel = uilabel(app.NewTab);
            app.GainVoltageAngleEditFieldLabel.Text = 'Gain Voltage/Angle';
            app.GainVoltageAngleEditField = uieditfield(app.NewTab, 'text');
            app.GainVoltageAngleEditField.ValueChangedFcn = createCallbackFcn(app, @GainVoltageAngleEditFieldValueChanged, true);
            app.HystrsisVaEditFieldLabel = uilabel(app.NewTab);
            app.HystrsisVaEditFieldLabel.Text = 'Hystérésis Va';
            app.HystrsisVaEditField = uieditfield(app.NewTab, 'text');
            app.HystrsisVaEditField.ValueChangedFcn = createCallbackFcn(app, @HystrsisVaEditFieldValueChanged, true);
            app.HystrsisVbEditFieldLabel = uilabel(app.NewTab);
            app.HystrsisVbEditFieldLabel.Text = 'Hystérésis Vb';
            app.HystrsisVbEditField = uieditfield(app.NewTab, 'text');
            app.HystrsisVbEditField.ValueChangedFcn = createCallbackFcn(app, @HystrsisVbEditFieldValueChanged, true);
            app.LimitedetensionEditFieldLabel = uilabel(app.NewTab);
            app.LimitedetensionEditFieldLabel.Text = 'Limite de tension';
            app.LimitedetensionEditField = uieditfield(app.NewTab, 'text');
            app.LimitedetensionEditField.ValueChangedFcn = createCallbackFcn(app, @LimitedetensionEditFieldValueChanged, true);
            app.GainLumireCourantEditFieldLabel = uilabel(app.NewTab);
            app.GainLumireCourantEditFieldLabel.Text = 'Gain Lumière/Courant';
            app.GainLumireCourantEditField = uieditfield(app.NewTab, 'text');
            app.GainLumireCourantEditField.ValueChangedFcn = createCallbackFcn(app, @GainLumireCourantEditFieldValueChanged, true);
            app.AccordIEditFieldLabel = uilabel(app.NewTab);
            app.AccordIEditFieldLabel.Text = 'Accord I';
            app.AccordIEditField = uieditfield(app.NewTab, 'text');
            app.AccordIEditField.ValueChangedFcn = createCallbackFcn(app, @AccordIEditFieldValueChanged, true);
            app.InverserpolarisationCheckBox = uicheckbox(app.NewTab);
            app.InverserpolarisationCheckBox.ValueChangedFcn = createCallbackFcn(app, @InverserpolarisationCheckBoxValueChanged, true);
            app.InverserpolarisationCheckBox.Text = 'Inverser polarisation';
            app.HiddenGroup = uitabgroup(app.UIFigure);
            app.HiddenGroup.Visible = 'off';
            app.hiddenTab = uitab(app.HiddenGroup);
            app.hiddenTab.Title = 'hidden';
            app.AutoentretienSwitchLabel = uilabel(app.UIFigure);
            app.AutoentretienSwitchLabel.Text = 'Auto-entretien';
            app.AutoentretienSwitch = uiswitch(app.UIFigure, 'slider');
            app.AutoentretienSwitch.Items = {'', 'Allumé'};
            app.AutoentretienSwitch.ValueChangedFcn = createCallbackFcn(app, @AutoentretienSwitchValueChanged, true);
            app.AutoentretienSwitch.Value = '';
            app.AutoaccordSwitchLabel = uilabel(app.UIFigure);
            app.AutoaccordSwitchLabel.Text = 'Auto-accord';
            app.AutoaccordSwitch = uiswitch(app.UIFigure, 'slider');
            app.AutoaccordSwitch.Items = {'', 'Allumé'};
            app.AutoaccordSwitch.ValueChangedFcn = createCallbackFcn(app, @AutoaccordSwitchValueChanged, true);
            app.AutoaccordSwitch.Value = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end